classdef bvGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PauseafterpreloadEditField     matlab.ui.control.EditField
        PauseafterpreloadEditFieldLabel  matlab.ui.control.Label
        LogButton                      matlab.ui.control.Button
        ExperimentlogTextArea          matlab.ui.control.TextArea
        ExperimentlogTextAreaLabel     matlab.ui.control.Label
        ITIEditField                   matlab.ui.control.EditField
        ITIEditFieldLabel              matlab.ui.control.Label
        FeatCopyButton                 matlab.ui.control.Button
        CopyButton                     matlab.ui.control.Button
        VariablesEditField             matlab.ui.control.EditField
        VariablesEditFieldLabel        matlab.ui.control.Label
        TestStimBtn                    matlab.ui.control.Button
        PreBlankText                   matlab.ui.control.EditField
        DelaysecsLabel                 matlab.ui.control.Label
        BuildSetButton                 matlab.ui.control.Button
        AnimalIDEditField              matlab.ui.control.EditField
        AnimalIDEditFieldLabel         matlab.ui.control.Label
        UITable_DAQs                   matlab.ui.control.Table
        FeedbackListBox                matlab.ui.control.ListBox
        RandomiseCheckBox              matlab.ui.control.CheckBox
        RepsEditField                  matlab.ui.control.EditField
        RepsEditFieldLabel             matlab.ui.control.Label
        SequenceRepeatsEditField       matlab.ui.control.EditField
        SequenceRepeatsEditFieldLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        SavesettingsButton             matlab.ui.control.Button
        BVServerEditField              matlab.ui.control.EditField
        BVServerEditFieldLabel         matlab.ui.control.Label
        Rewardv2Button                 matlab.ui.control.Button
        Rewardv1Button                 matlab.ui.control.Button
        Closevalve2Button              matlab.ui.control.Button
        Openvalve2Button               matlab.ui.control.Button
        Closevalve1Button              matlab.ui.control.Button
        Openvalve1Button               matlab.ui.control.Button
        NewFeatureListBoxLabel_2       matlab.ui.control.Label
        NewButton                      matlab.ui.control.Button
        ImportSchemaButton             matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        ButtonRemoveStim               matlab.ui.control.Button
        ButtonAddStim                  matlab.ui.control.Button
        ButtonRemoveFeature            matlab.ui.control.Button
        ButtonAddFeature               matlab.ui.control.Button
        NewFeatureListBox              matlab.ui.control.ListBox
        NewFeatureListBoxLabel         matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        FeatureListBox                 matlab.ui.control.ListBox
        FeatureListBoxLabel            matlab.ui.control.Label
        StimulusListBox                matlab.ui.control.ListBox
        StimulusListBoxLabel           matlab.ui.control.Label
    end


    properties (Access = public)
        abortFlag = 0; % Whether you want to abort if stimulus run is ongoing
        photoStimStarted = false; % Whether opto_2p photostimulation has been triggered in this run
        lastExperimentUser = ''; % Last selected experiment user for this app session
        currentExperimentLogPath = ''; % Experiment-level log for the current session
    end

    methods (Access = private)
        function pauseWithEvents(app, pauseTime)
            % pauseWithEvents pauses for a specified time while processing events
            %
            % Inputs:
            %   pauseTime - Number of seconds to pause
        
            startTime = tic; % Start the timer
            while toc(startTime) < pauseTime
                drawnow; % Process any pending events or callbacks
            end
        end        
      
        function [result, detail] = send_udp_command(app, server, port, msg, timeoutPeriod)
            if nargin < 5 || isempty(timeoutPeriod)
                timeoutPeriod = 30;
            end
            udpSocket = [];
            detail = '';
        
            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                % Send the provided message
                app.writeUdpPayload(udpSocket, server, port, msg);
        
                % Wait for the response
                response = app.waitForUdpBytes(udpSocket, timeoutPeriod, false);
                if isempty(response)
                    result = -1; % Timeout reached, return failure
                    detail = 'Timed out waiting for UDP reply.';
                    return;
                end
        
                % Read and process the response
                responseStr = char(response(:)');
                responseChars = double(responseStr);
                keepMask = responseChars >= 32 | responseChars == 9 | responseChars == 10 | responseChars == 13;
                responseStr = responseStr(keepMask);
                responseStr = strtrim(responseStr);
                detail = responseStr;
                
                % Check the server response and return success or failure
                if strcmp(responseStr, '1') || strcmpi(responseStr, 'OK')
                    result = 1; % Success
                elseif strcmp(responseStr, '-1')
                    result = -1; % Failure
                else
                    result = -1; % Unexpected response
                    detail = ['Unexpected reply: ', responseStr];
                end
            catch err
                % If an error occurs, return failure
                result = -1;
                detail = err.message;
            end
        
            % Close the UDP socket
            app.cleanupUdpSocket(udpSocket);
            
        end

        function [success, detail] = send_udp_command_no_reply(app, server, port, msg)
            success = true;
            detail = '';
            udpSocket = [];

            try
                udpSocket = app.createUdpSocket(5);
                app.writeUdpPayload(udpSocket, server, port, msg);
            catch err
                success = false;
                detail = err.message;
            end

            app.cleanupUdpSocket(udpSocket);
        end

        function [result, detail] = send_udp_command_with_prompt(app, server, port, msg, initialTimeout, promptTitle)
            if nargin < 6 || isempty(promptTitle)
                promptTitle = 'UDP wait';
            end

            waitTimeout = initialTimeout;
            while true
                [result, detail] = app.send_udp_command(server, port, msg, waitTimeout);
                if result == 1
                    return;
                end

                if strcmp(detail, 'Timed out waiting for UDP reply.')
                    choice = questdlg( ...
                        ['No UDP reply received after ',num2str(waitTimeout),' seconds. Keep waiting?'], ...
                        promptTitle, ...
                        'Keep waiting', 'Stop waiting', 'Stop waiting');
                    if strcmp(choice, 'Keep waiting')
                        waitTimeout = 30;
                        continue;
                    end
                end
                return;
            end
        end

        function repoRoot = getRepoRoot(app)
            appPath = which(class(app));
            if isempty(appPath)
                repoRoot = pwd;
            else
                repoRoot = fileparts(appPath);
            end
        end

        function config = getRepoConfig(app)
            repoRoot = app.getRepoRoot();
            machineName = app.getMachineName();
            configDir = fullfile(repoRoot,'configs');
            machineConfigRoot = fullfile(configDir,machineName);
            iniPath = fullfile(machineConfigRoot,'bvGUI.ini');
            if ~exist(iniPath,'file')
                error('bvGUI:MissingMachineConfig', 'Missing machine config: %s', iniPath);
            end
            iniData = app.readIniFile(iniPath);

            config = struct();
            config.repoRoot = repoRoot;
            config.configDir = configDir;
            config.machineName = machineName;
            config.machineConfigRoot = machineConfigRoot;
            config.iniPath = iniPath;
            config.featuresDir = fullfile(machineConfigRoot,'features');
            config.stimsetsDir = fullfile(machineConfigRoot,'stimsets');
            config.daqStartDir = fullfile(machineConfigRoot,'daqStart');
            config.daqStopDir = fullfile(machineConfigRoot,'daqStop');
            config.iniData = iniData;
            config.bvServer = app.getIniValue(iniData,'settings','bv_server','127.0.0.1');
            config.localSaveRoot = app.resolveConfigPath(repoRoot, app.getIniValue(iniData,'paths','local_save_root','c:\local_repository'));
            config.remoteSaveRoot = app.resolveConfigPath(repoRoot, app.getIniValue(iniData,'paths','remote_save_root','\\AR-LAB-NAS1\DataServer\Remote_Repository'));
            config.pythonExe = app.resolveConfigPath(repoRoot, app.getIniValue(iniData,'paths','python_exe',''));
            config.hashScript = app.resolveConfigPath(repoRoot, app.getIniValue(iniData,'paths','hash_script',''));
            config.opto2pListener = strtrim(app.getIniValue(iniData,'network','opto_2p_listener',''));
            config.opto2pPort = str2double(strtrim(app.getIniValue(iniData,'network','opto_2p_port','')));
        end

        function machineName = getMachineName(app)
            machineName = getenv('COMPUTERNAME');
            if isempty(machineName)
                machineName = getenv('HOSTNAME');
            end
            if isempty(machineName)
                machineName = 'default';
            end
            machineName = strtrim(machineName);
        end

        function addRepoSupportPaths(app, repoRoot)
            supportPaths = {
                fullfile(repoRoot,'bv_matlab')
                fullfile(repoRoot,'UDPqML')
            };

            for iPath = 1:numel(supportPaths)
                if exist(supportPaths{iPath},'dir')
                    addpath(supportPaths{iPath});
                end
            end
        end

        function iniData = readIniFile(app, iniPath)
            iniData = struct();
            if ~exist(iniPath,'file')
                return;
            end

            iniText = fileread(iniPath);
            iniLines = splitlines(iniText);
            currentSection = 'global';
            iniData.(currentSection) = struct();

            for iLine = 1:length(iniLines)
                lineText = strtrim(iniLines{iLine});
                if isempty(lineText) || startsWith(lineText,';') || startsWith(lineText,'#')
                    continue;
                end

                if startsWith(lineText,'[') && endsWith(lineText,']')
                    currentSection = matlab.lang.makeValidName(lower(strtrim(lineText(2:end-1))));
                    if ~isfield(iniData,currentSection)
                        iniData.(currentSection) = struct();
                    end
                    continue;
                end

                separatorIdx = strfind(lineText,'=');
                if isempty(separatorIdx)
                    continue;
                end

                keyName = strtrim(lineText(1:separatorIdx(1)-1));
                keyValue = strtrim(lineText(separatorIdx(1)+1:end));

                if length(keyValue) >= 2
                    if (startsWith(keyValue,'"') && endsWith(keyValue,'"')) || (startsWith(keyValue,'''') && endsWith(keyValue,''''))
                        keyValue = keyValue(2:end-1);
                    end
                end

                keyName = matlab.lang.makeValidName(lower(keyName));
                iniData.(currentSection).(keyName) = keyValue;
            end
        end

        function value = getIniValue(app, iniData, sectionName, keyName, defaultValue)
            sectionName = matlab.lang.makeValidName(lower(sectionName));
            keyName = matlab.lang.makeValidName(lower(keyName));

            value = defaultValue;
            if isfield(iniData,sectionName) && isfield(iniData.(sectionName),keyName)
                value = iniData.(sectionName).(keyName);
            end
        end

        function iniData = setIniValue(app, iniData, sectionName, keyName, value)
            sectionName = matlab.lang.makeValidName(lower(sectionName));
            keyName = matlab.lang.makeValidName(lower(keyName));
            if ~isfield(iniData,sectionName)
                iniData.(sectionName) = struct();
            end
            iniData.(sectionName).(keyName) = value;
        end

        function writeIniFile(app, iniPath, iniData)
            sectionNames = fieldnames(iniData);
            lines = {};
            for iSection = 1:numel(sectionNames)
                sectionName = sectionNames{iSection};
                if strcmp(sectionName,'global')
                    continue;
                end
                lines{end+1} = ['[', sectionName, ']']; %#ok<AGROW>
                keyNames = fieldnames(iniData.(sectionName));
                for iKey = 1:numel(keyNames)
                    keyName = keyNames{iKey};
                    keyValue = iniData.(sectionName).(keyName);
                    if ~(ischar(keyValue) || isstring(keyValue))
                        keyValue = num2str(keyValue);
                    end
                    lines{end+1} = [keyName, ' = ', char(keyValue)]; %#ok<AGROW>
                end
                lines{end+1} = ''; %#ok<AGROW>
            end
            iniText = strjoin(lines,newline);
            fid = fopen(iniPath,'w');
            if fid == -1
                error('bvGUI:IniWriteFailed','Unable to write ini file: %s', iniPath);
            end
            cleaner = onCleanup(@() fclose(fid));
            fprintf(fid,'%s',iniText);
        end

        function resolvedPath = resolveConfigPath(app, repoRoot, configuredPath)
            resolvedPath = configuredPath;
            if isempty(configuredPath)
                return;
            end

            isDrivePath = ~isempty(regexp(configuredPath,'^[A-Za-z]:[\\/]', 'once'));
            isUncPath = startsWith(configuredPath,'\\');
            isUnixPath = startsWith(configuredPath,'/');

            if ~(isDrivePath || isUncPath || isUnixPath)
                resolvedPath = fullfile(repoRoot, configuredPath);
            end
        end

        function completeStimSeq = buildCompleteStimSeq(app)
            global bvData;

            completeStimSeq = [];
            seqReps = str2num(app.SequenceRepeatsEditField.Value); %#ok<ST2NM>

            for iSeqRep = 1:seqReps
                singleRepSeq = [];
                for iStim = 1:length(bvData.expData.stims)
                    stimReps = bvData.expData.stims(iStim).reps;
                    singleRepSeq = [singleRepSeq,repmat(iStim,[1 stimReps])]; %#ok<AGROW>
                end
                if app.RandomiseCheckBox.Value == true
                    completeStimSeq = [completeStimSeq,singleRepSeq(randperm(length(singleRepSeq)))]; %#ok<AGROW>
                else
                    completeStimSeq = [completeStimSeq,singleRepSeq]; %#ok<AGROW>
                end
            end
        end

        function [prepData, errMsg] = collectOpto2pPrepData(app, expDataEval, completeStimSeq)
            prepData = struct('enabled', false, 'schemaName', '', 'seqNums', []);
            errMsg = '';
            schemaName = '';
            seqNums = [];

            for iTrial = 1:length(completeStimSeq)
                stimIdx = completeStimSeq(iTrial);
                [trialData, errMsg] = app.collectTrialOpto2pData(expDataEval, stimIdx);
                if ~isempty(errMsg)
                    return;
                end
                if ~trialData.enabled
                    continue;
                end

                if isempty(schemaName)
                    schemaName = trialData.schemaName;
                elseif ~strcmp(schemaName,trialData.schemaName)
                    errMsg = ['Conflicting opto_2p schema_name values: ',schemaName,' vs ',trialData.schemaName];
                    return;
                end

                if ~ismember(trialData.seqNum,seqNums)
                    seqNums(end+1) = trialData.seqNum; %#ok<AGROW>
                end
            end

            if isempty(seqNums)
                return;
            end

            prepData.enabled = true;
            prepData.schemaName = schemaName;
            prepData.seqNums = seqNums;
        end

        function [trialData, errMsg] = collectTrialOpto2pData(app, expDataEval, stimIdx)
            trialData = struct('enabled', false, 'schemaName', '', 'seqNum', []);
            errMsg = '';
            schemaName = '';
            seqNum = [];
            enabledOptoCount = 0;

            for iFeature = 1:length(expDataEval(stimIdx).features)
                feature = expDataEval(stimIdx).features(iFeature);
                if ~strcmp(feature.name{1},'opto_2p')
                    continue;
                end

                featureParams = cell2struct(feature.vals',feature.params);
                if isfield(featureParams,'enable') && ~strcmp(strtrim(featureParams.enable),'1')
                    continue;
                end

                enabledOptoCount = enabledOptoCount + 1;
                if enabledOptoCount > 1
                    errMsg = ['Only one enabled opto_2p feature is allowed in stimulus ',num2str(stimIdx)];
                    return;
                end

                if ~isfield(featureParams,'schema_name') || isempty(strtrim(featureParams.schema_name))
                    errMsg = ['Missing schema_name in opto_2p feature for stimulus ',num2str(stimIdx)];
                    return;
                end
                if ~isfield(featureParams,'seq_num') || isempty(strtrim(featureParams.seq_num))
                    errMsg = ['Missing seq_num in opto_2p feature for stimulus ',num2str(stimIdx)];
                    return;
                end

                currentSchemaName = strtrim(featureParams.schema_name);
                currentSeqNum = str2double(featureParams.seq_num);
                if isnan(currentSeqNum) || ~isfinite(currentSeqNum) || currentSeqNum ~= floor(currentSeqNum)
                    errMsg = ['Invalid opto_2p seq_num: ',featureParams.seq_num];
                    return;
                end

                if isempty(schemaName)
                    schemaName = currentSchemaName;
                elseif ~strcmp(schemaName,currentSchemaName)
                    errMsg = ['Conflicting opto_2p schema_name values within stimulus ',num2str(stimIdx)];
                    return;
                end

                if isempty(seqNum)
                    seqNum = currentSeqNum;
                elseif seqNum ~= currentSeqNum
                    errMsg = ['Conflicting opto_2p seq_num values within stimulus ',num2str(stimIdx)];
                    return;
                end
            end

            if isempty(seqNum)
                return;
            end

            trialData.enabled = true;
            trialData.schemaName = schemaName;
            trialData.seqNum = seqNum;
        end

        function [conditionCatalog, conditionIndexByStimIdx, errMsg] = buildStimulusConditionCatalog(app, expDataEval, stimIdxList)
            global bvData;
            errMsg = '';

            uniqueStimIdxList = unique(stimIdxList, 'stable');
            stimulusConditions = cell(1, numel(uniqueStimIdxList));
            conditionIndexByStimIdx = nan(1, length(expDataEval));

            trialDataByStim = cell(1, numel(uniqueStimIdxList));
            anyOpto2p = false;
            for iCondition = 1:numel(uniqueStimIdxList)
                stimIdx = uniqueStimIdxList(iCondition);
                [trialData, trialErr] = app.collectTrialOpto2pData(expDataEval, stimIdx);
                if ~isempty(trialErr)
                    errMsg = trialErr;
                    conditionCatalog = {};
                    conditionIndexByStimIdx = [];
                    return;
                end
                trialDataByStim{iCondition} = trialData;
                if trialData.enabled
                    anyOpto2p = true;
                end
            end

            for iCondition = 1:numel(uniqueStimIdxList)
                stimIdx = uniqueStimIdxList(iCondition);
                featureEntries = cell(1, 0);
                trialData = trialDataByStim{iCondition};

                if anyOpto2p
                    if ~trialData.enabled
                        errMsg = ['Online Analysis v1 requires exactly one enabled opto_2p feature in stimulus ',num2str(stimIdx)];
                        conditionCatalog = {};
                        conditionIndexByStimIdx = [];
                        return;
                    end
                    featureEntries{1} = struct( ...
                        'name', 'opto_2p', ...
                        'params', struct( ...
                            'schema_name', trialData.schemaName, ...
                            'seq_num', trialData.seqNum));
                else
                    features = expDataEval(stimIdx).features;
                    for iFeature = 1:length(features)
                        feature = features(iFeature);
                        featureName = feature.name{1};
                        featureParams = cell2struct(feature.vals',feature.params);

                        if any(strcmp(featureName,{'opto','opto_2p'}))
                            if isfield(featureParams,'enable') && ~strcmp(strtrim(featureParams.enable),'1')
                                continue;
                            end
                        end

                        paramsStruct = struct();
                        for iParam = 1:numel(feature.params)
                            paramName = feature.params{iParam};
                            paramsStruct.(paramName) = app.convertStimParamForJson(feature.vals{iParam});
                        end

                        featureEntries{end+1} = struct( ... %#ok<AGROW>
                            'name', featureName, ...
                            'params', paramsStruct);
                    end
                end

                stimulusConditions{iCondition} = struct( ...
                    'stimulus_id', stimIdx, ...
                    'reps', bvData.expData.stims(stimIdx).reps, ...
                    'features', {featureEntries});
                conditionIndexByStimIdx(stimIdx) = iCondition - 1;
            end

            conditionCatalog = stimulusConditions;
        end

        function jsonValue = convertStimParamForJson(app, rawValue)
            jsonValue = rawValue;

            if isstring(rawValue)
                rawValue = char(rawValue);
            end

            if ischar(rawValue)
                valueText = strtrim(rawValue);
                numericValue = str2num(valueText); %#ok<ST2NM>
                if ~isempty(numericValue)
                    jsonValue = numericValue;
                else
                    jsonValue = valueText;
                end
            end
        end

        function [success, errMsg] = sendExperimentParams(app, config, expID, stimulusConditions, trialConditionIndices, jsonDumpDir)
            success = true;
            errMsg = '';
            if nargin < 6
                jsonDumpDir = '';
            end

            if isempty(config.opto2pListener)
                app.debugMessage(['Opto server not configured on machine ',config.machineName,': network.opto_2p_listener is not configured; not sending trial info.']);
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                app.debugMessage(['Opto server not configured on machine ',config.machineName,': network.opto_2p_port is not configured; not sending trial info.']);
                return;
            end

            timeoutPeriod = 600;
            % Updating an experiment clears ScanImage state and can take several seconds.
            % Do not retransmit during that transition, because each retry is the same request.
            replyWaitPeriod = 10;

            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                paramsPayload = struct('expID', expID);
                paramsPayload.stimulus_conditions = stimulusConditions;
                paramsPayload.trial_condition_indices = trialConditionIndices;
                paramsJsonPayload = jsonencode(paramsPayload);
                notificationPayload = struct('action', 'update_experiment_params', 'expID', expID);
                notificationPayload.stimulus_conditions = stimulusConditions;
                notificationPayload.trial_condition_indices = trialConditionIndices;
                jsonPayload = jsonencode(notificationPayload);
                if ~isempty(jsonDumpDir)
                    jsonPath = fullfile(jsonDumpDir, [expID,'_update_experiment_params.json']);
                    [fileId, fileErr] = fopen(jsonPath, 'w');
                    if fileId < 0
                        success = false;
                        errMsg = ['Could not write update_experiment_params JSON to ',jsonPath,': ',fileErr];
                        return;
                    end
                    fwrite(fileId, paramsJsonPayload, 'char');
                    fwrite(fileId, newline, 'char');
                    fclose(fileId);
                    app.debugMessage(['Saved update_experiment_params JSON to ',jsonPath]);
                    notificationPayload = struct( ...
                        'action', 'update_experiment_params', ...
                        'expID', expID, ...
                        'params_path', jsonPath);
                    jsonPayload = jsonencode(notificationPayload);
                end
                fprintf(1,'UPDATE_EXPERIMENT_PARAMS_JSON_BEGIN\n%s\nUPDATE_EXPERIMENT_PARAMS_JSON_END\n', paramsJsonPayload);
                fprintf(1,'UPDATE_EXPERIMENT_PARAMS_UDP_JSON_BEGIN\n%s\nUPDATE_EXPERIMENT_PARAMS_UDP_JSON_END\n', jsonPayload);
                maxAttempts = 1;
                response = [];
                for attempt = 1:maxAttempts
                    app.debugMessage(['Sending update_experiment_params attempt ',num2str(attempt),'/',num2str(maxAttempts),' for ',num2str(numel(stimulusConditions)),' stimulus conditions via ',config.opto2pListener,':',num2str(config.opto2pPort)]);
                    app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));
                    response = app.waitForUdpBytes(udpSocket, replyWaitPeriod, true);
                    if ~isempty(response)
                        break;
                    end
                    if attempt < maxAttempts
                        app.debugMessage('No update_experiment_params reply received; retrying.');
                    end
                end
                if isempty(response)
                    success = false;
                    errMsg = ['No update_experiment_params reply received from the Opto GUI at ',config.opto2pListener,':',num2str(config.opto2pPort),' after ',num2str(maxAttempts),' attempts. Check that the Opto GUI listener is active on UDP port 1813.'];
                    return;
                end
                [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                if ~decodedOk
                    success = false;
                    errMsg = sprintf('Invalid update_experiment_params JSON reply: %s', app.jsonValueToText(responseText));
                    return;
                end

                if ~isfield(reply,'action') || ~strcmp(reply.action,'update_experiment_params')
                    success = false;
                    errMsg = 'Unexpected update_experiment_params action in reply.';
                    return;
                end
                if ~isfield(reply,'status')
                    success = false;
                    errMsg = 'Missing status in update_experiment_params reply.';
                    return;
                end

                app.debugMessage(['update_experiment_params reply.status class: ',class(reply.status)]);
                replyStatus = app.jsonValueToText(reply.status);
                if strcmp(replyStatus,'error')
                    replyError = 'Unknown update_experiment_params error';
                    if isfield(reply,'error')
                        app.debugMessage(['update_experiment_params reply.error class: ',class(reply.error)]);
                        replyError = app.jsonValueToText(reply.error);
                    end
                    success = false;
                    errMsg = ['update_experiment_params error: ',replyError];
                    return;
                end
                if ~strcmp(replyStatus,'ready')
                    success = false;
                    errMsg = ['Unexpected update_experiment_params status: ',replyStatus];
                    return;
                end

                app.debugMessage('update_experiment_params acknowledged.');
            catch err
                success = false;
                errMsg = ['update_experiment_params failed: ',err.message];
                for iStack = 1:numel(err.stack)
                    app.debugMessage(['update_experiment_params stack ',num2str(iStack),': ',err.stack(iStack).name,' line ',num2str(err.stack(iStack).line)]);
                end
            end
        end

        function [success, errMsg] = startOpto2pTrial(app, config, trialIndex)
            success = true;
            errMsg = '';

            if isempty(config.opto2pListener)
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_listener is not configured.'];
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_port is not configured.'];
                return;
            end

            timeoutPeriod = 600;

            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                payload = struct( ...
                    'action', 'start_trial', ...
                    'trial_index', trialIndex);
                jsonPayload = jsonencode(payload);
                app.debugMessage(['Sending start_trial index ',num2str(trialIndex),' via ',config.opto2pListener,':',num2str(config.opto2pPort)]);
                app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));

                response = app.waitForUdpBytes(udpSocket, timeoutPeriod, true);
                if isempty(response)
                    success = false;
                    errMsg = ['Timed out waiting for start_trial confirmation for index ',num2str(trialIndex)];
                    return;
                end
                [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                if ~decodedOk
                    success = false;
                    errMsg = sprintf('Invalid start_trial JSON reply for index %d: %s', trialIndex, app.jsonValueToText(responseText));
                    return;
                end

                if ~isfield(reply,'action') || ~strcmp(reply.action,'start_trial')
                    success = false;
                    errMsg = ['Unexpected start_trial action for index ',num2str(trialIndex)];
                    return;
                end
                if ~isfield(reply,'status')
                    success = false;
                    errMsg = ['Missing status in start_trial reply for index ',num2str(trialIndex)];
                    return;
                end

                replyStatus = app.jsonValueToText(reply.status);
                if strcmp(replyStatus,'error')
                    replyError = 'Unknown start_trial error';
                    if isfield(reply,'error')
                        replyError = app.jsonValueToText(reply.error);
                    end
                    success = false;
                    errMsg = ['start_trial error for index ',num2str(trialIndex),': ',replyError];
                    return;
                end
                if ~strcmp(replyStatus,'ready')
                    success = false;
                    errMsg = ['Unexpected start_trial status for index ',num2str(trialIndex),': ',replyStatus];
                    return;
                end

                app.debugMessage(['start_trial ready for index ',num2str(trialIndex)]);
            catch err
                success = false;
                errMsg = ['start_trial failed: ',err.message];
            end
        end

        function needsDefaultGrating = trialNeedsDefaultBonvisionTrigger(app, expDataEval, stimIdx)
            needsDefaultGrating = false;
            hasOpto2p = false;
            hasBonvisionTriggerFeature = false;

            for iFeature = 1:length(expDataEval(stimIdx).features)
                feature = expDataEval(stimIdx).features(iFeature);
                featureName = feature.name{1};

                switch featureName
                    case {'grating','movie','go_nogo'}
                        hasBonvisionTriggerFeature = true;
                    case 'opto_2p'
                        featureParams = cell2struct(feature.vals',feature.params);
                        if ~isfield(featureParams,'enable') || strcmp(strtrim(featureParams.enable),'1')
                            hasOpto2p = true;
                        end
                end
            end

            needsDefaultGrating = hasOpto2p && ~hasBonvisionTriggerFeature;
        end

        function addDefaultBonvisionTriggerGrating(app, rig)
            g = [];
            g.angle = 0;
            g.width = 1;
            g.height = 1;
            g.size = 1;
            g.x = 0;
            g.y = 0;
            g.contrast = 0;
            g.opacity = 1;
            g.phase = 0;
            g.freq = 0.01;
            g.speed = 0;
            g.dcycle = 1;
            g.onset = 0;
            g.duration = 0.1;
            rig.gratings(g);
        end

        function [success, errMsg] = runOpto2pPrep(app, config, expID, prepData)
            success = true;
            errMsg = '';

            if ~prepData.enabled
                return;
            end

            if isempty(config.opto2pListener)
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_listener is not configured.'];
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_port is not configured.'];
                return;
            end

            app.debugMessage('Computing masks');

            timeoutPeriod = 600;
            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                payload = struct( ...
                    'action', 'prep_patterns', ...
                    'schema_name', prepData.schemaName, ...
                    'expID', expID, ...
                    'seq_nums', prepData.seqNums);
                jsonPayload = jsonencode(payload);
                seqNumSummary = strjoin(app.jsonValueToTextCell(prepData.seqNums),', ');
                app.debugMessage(['Preparing opto_2p masks for seq_nums [',seqNumSummary,'] via ',config.opto2pListener,':',num2str(config.opto2pPort)]);
                app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));

                response = app.waitForUdpBytes(udpSocket, timeoutPeriod, true);
                if isempty(response)
                    success = false;
                    errMsg = ['Timed out waiting for opto_2p confirmation for seq_nums [',seqNumSummary,']'];
                    return;
                end
                [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                if ~decodedOk
                    success = false;
                    errMsg = sprintf('Invalid opto_2p JSON reply for seq_nums [%s]: %s', seqNumSummary, app.jsonValueToText(responseText));
                    return;
                end

                if ~isfield(reply,'action') || ~strcmp(reply.action,'prep_patterns')
                    success = false;
                    errMsg = 'Unexpected opto_2p prep action in reply.';
                    return;
                end
                if ~isfield(reply,'schema_name') || ~strcmp(app.jsonValueToText(reply.schema_name),prepData.schemaName)
                    success = false;
                    errMsg = 'Schema mismatch in opto_2p prep reply.';
                    return;
                end
                if ~isfield(reply,'expID') || ~strcmp(app.jsonValueToText(reply.expID),expID)
                    success = false;
                    errMsg = 'expID mismatch in opto_2p prep reply.';
                    return;
                end
                if ~isfield(reply,'status')
                    success = false;
                    errMsg = 'Missing status in opto_2p prep reply.';
                    return;
                end

                replyStatus = app.jsonValueToText(reply.status);
                if strcmp(replyStatus,'error')
                    replyError = 'Unknown opto_2p error';
                    if isfield(reply,'error')
                        replyError = app.jsonValueToText(reply.error);
                    end
                    success = false;
                    errMsg = ['Opto_2p prep error: ',replyError];
                    return;
                end
                if ~strcmp(replyStatus,'ready')
                    success = false;
                    errMsg = ['Unexpected opto_2p prep status: ',replyStatus];
                    return;
                end

                preparedSeqNumsSummary = '';
                if isfield(reply,'prepared_seq_nums')
                    preparedSeqNumsSummary = strjoin(app.jsonValueToTextCell(reply.prepared_seq_nums),', ');
                end

                preparedSequenceNamesSummary = '';
                if isfield(reply,'prepared_sequence_names')
                    preparedSequenceNamesSummary = strjoin(app.jsonValueToTextCell(reply.prepared_sequence_names),', ');
                end

                patternSummary = '';
                if isfield(reply,'pattern_names')
                    patternNames = app.jsonValueToTextCell(reply.pattern_names);
                    patternSummary = strjoin(patternNames,', ');
                end

                app.debugMessage(['Opto_2p prep ready for schema ',prepData.schemaName,' and seq_nums [',seqNumSummary,']']);
                if ~isempty(preparedSeqNumsSummary)
                    app.debugMessage(['Prepared seq_nums: ',preparedSeqNumsSummary]);
                end
                if ~isempty(preparedSequenceNamesSummary)
                    app.debugMessage(['Prepared sequences: ',preparedSequenceNamesSummary]);
                end
                if ~isempty(patternSummary)
                    app.debugMessage(['Prepared patterns: ',patternSummary]);
                end
            catch err
                success = false;
                errMsg = ['Opto_2p prep failed: ',err.message];
            end
        end

        function [success, errMsg] = triggerOpto2pForTrial(app, config, expID, trialData)
            success = true;
            errMsg = '';

            if ~trialData.enabled
                return;
            end

            if isempty(config.opto2pListener)
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_listener is not configured.'];
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                success = false;
                errMsg = ['Opto_2p is not available on machine ',config.machineName,': network.opto_2p_port is not configured.'];
                return;
            end

            timeoutPeriod = 600;

            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                payload = struct( ...
                    'action', 'trigger_photo_stim', ...
                    'schema_name', trialData.schemaName, ...
                    'expID', expID, ...
                    'seq_num', trialData.seqNum);
                jsonPayload = jsonencode(payload);
                app.debugMessage(['Triggering opto_2p for seq_num ',num2str(trialData.seqNum),' via ',config.opto2pListener,':',num2str(config.opto2pPort)]);
                app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));

                response = app.waitForUdpBytes(udpSocket, timeoutPeriod, true);
                if isempty(response)
                    success = false;
                    errMsg = ['Timed out waiting for opto_2p trigger confirmation for seq_num ',num2str(trialData.seqNum)];
                    return;
                end
                [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                if ~decodedOk
                    success = false;
                    errMsg = sprintf('Invalid opto_2p trigger JSON reply for seq_num %d: %s', trialData.seqNum, app.jsonValueToText(responseText));
                    return;
                end

                if ~isfield(reply,'action') || ~strcmp(reply.action,'trigger_photo_stim')
                    success = false;
                    errMsg = ['Unexpected opto_2p trigger action for seq_num ',num2str(trialData.seqNum)];
                    return;
                end
                if ~isfield(reply,'schema_name') || ~strcmp(app.jsonValueToText(reply.schema_name),trialData.schemaName)
                    success = false;
                    errMsg = ['Schema mismatch in opto_2p trigger reply for seq_num ',num2str(trialData.seqNum)];
                    return;
                end
                if ~isfield(reply,'expID') || ~strcmp(app.jsonValueToText(reply.expID),expID)
                    success = false;
                    errMsg = ['expID mismatch in opto_2p trigger reply for seq_num ',num2str(trialData.seqNum)];
                    return;
                end
                if ~isfield(reply,'seq_num')
                    success = false;
                    errMsg = ['Missing seq_num in opto_2p trigger reply for seq_num ',num2str(trialData.seqNum)];
                    return;
                end

                replySeqNum = app.jsonValueToDouble(reply.seq_num);
                if isnan(replySeqNum) || replySeqNum ~= trialData.seqNum
                    success = false;
                    errMsg = ['seq_num mismatch in opto_2p trigger reply for seq_num ',num2str(trialData.seqNum)];
                    return;
                end
                if ~isfield(reply,'status')
                    success = false;
                    errMsg = ['Missing status in opto_2p trigger reply for seq_num ',num2str(trialData.seqNum)];
                    return;
                end

                replyStatus = app.jsonValueToText(reply.status);
                if strcmp(replyStatus,'error')
                    replyError = 'Unknown opto_2p trigger error';
                    if isfield(reply,'error')
                        replyError = app.jsonValueToText(reply.error);
                    end
                    success = false;
                    errMsg = ['Opto_2p trigger error for seq_num ',num2str(trialData.seqNum),': ',replyError];
                    return;
                end
                if ~strcmp(replyStatus,'ready')
                    success = false;
                    errMsg = ['Unexpected opto_2p trigger status for seq_num ',num2str(trialData.seqNum),': ',replyStatus];
                    return;
                end

                sequenceName = '';
                if isfield(reply,'sequence_name')
                    sequenceName = app.jsonValueToText(reply.sequence_name);
                end
                stimulusGroupsSummary = '';
                if isfield(reply,'stimulus_groups')
                    stimulusGroupsSummary = strjoin(app.jsonValueToTextCell(reply.stimulus_groups),', ');
                end

                if isempty(stimulusGroupsSummary)
                    app.debugMessage(['Opto_2p trigger ready for seq_num ',num2str(trialData.seqNum),' (',sequenceName,')']);
                else
                    app.debugMessage(['Opto_2p trigger ready for seq_num ',num2str(trialData.seqNum),' (',sequenceName,'): ',stimulusGroupsSummary]);
                end
                app.photoStimStarted = true;
            catch err
                success = false;
                errMsg = ['Opto_2p trigger failed: ',err.message];
            end
        end

        function [success, errMsg] = abortOpto2p(app, config)
            success = true;
            errMsg = '';

            if ~app.photoStimStarted
                return;
            end

            if isempty(config.opto2pListener)
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                return;
            end

            timeoutPeriod = 600;

            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                payload = struct('action', 'abort_photo_stim');
                jsonPayload = jsonencode(payload);
                app.debugMessage(['Sending abort_photo_stim via ',config.opto2pListener,':',num2str(config.opto2pPort)]);
                app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));

                response = app.waitForUdpBytes(udpSocket, timeoutPeriod, true);
                if isempty(response)
                    success = false;
                    errMsg = 'Timed out waiting for abort_photo_stim confirmation.';
                    return;
                end
                [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                if ~decodedOk
                    success = false;
                    errMsg = sprintf('Invalid abort_photo_stim JSON reply: %s', app.jsonValueToText(responseText));
                    return;
                end

                if ~isfield(reply,'action') || ~strcmp(reply.action,'abort_photo_stim')
                    success = false;
                    errMsg = 'Unexpected abort_photo_stim action in reply.';
                    return;
                end
                if ~isfield(reply,'status')
                    success = false;
                    errMsg = 'Missing status in abort_photo_stim reply.';
                    return;
                end

                replyStatus = app.jsonValueToText(reply.status);
                if strcmp(replyStatus,'error')
                    replyError = 'Unknown abort_photo_stim error';
                    if isfield(reply,'error')
                        replyError = app.jsonValueToText(reply.error);
                    end
                    success = false;
                    errMsg = ['abort_photo_stim error: ',replyError];
                    return;
                end
                if ~strcmp(replyStatus,'ready')
                    success = false;
                    errMsg = ['Unexpected abort_photo_stim status: ',replyStatus];
                    return;
                end

                app.debugMessage('abort_photo_stim acknowledged.');
                app.photoStimStarted = false;
            catch err
                success = false;
                errMsg = ['abort_photo_stim failed: ',err.message];
            end
        end

        function [success, errMsg] = waitForOpto2pIdle(app, config)
            success = true;
            errMsg = '';

            if isempty(config.opto2pListener)
                return;
            end
            if isnan(config.opto2pPort) || ~isfinite(config.opto2pPort) || config.opto2pPort <= 0
                return;
            end

            timeoutPeriod = 600;
            pollInterval = 0.25;
            startTime = tic;
            app.debugMessage('Check photostimulation completed');

            try
                udpSocket = app.createUdpSocket(timeoutPeriod);
                udpCleaner = onCleanup(@() app.cleanupUdpSocket(udpSocket)); %#ok<NASGU>

                while true
                    payload = struct('action', 'check_idle');
                    jsonPayload = jsonencode(payload);
                    app.writeUdpPayload(udpSocket, config.opto2pListener, config.opto2pPort, unicode2native(jsonPayload,'UTF-8'));

                    remainingTimeout = max(timeoutPeriod - toc(startTime), 0);
                    response = app.waitForUdpBytes(udpSocket, remainingTimeout, true);
                    if isempty(response)
                        success = false;
                        errMsg = 'Timed out waiting for check_idle confirmation.';
                        return;
                    end
                    [reply, responseText, decodedOk] = app.decodeUdpJsonReply(response);
                    if ~decodedOk
                        success = false;
                        errMsg = sprintf('Invalid check_idle JSON reply: %s', app.jsonValueToText(responseText));
                        return;
                    end

                    if ~isfield(reply,'action') || ~strcmp(reply.action,'check_idle')
                        success = false;
                        errMsg = 'Unexpected check_idle action in reply.';
                        return;
                    end
                    if ~isfield(reply,'status')
                        success = false;
                        errMsg = 'Missing status in check_idle reply.';
                        return;
                    end

                    replyStatus = app.jsonValueToText(reply.status);
                    if strcmp(replyStatus,'error')
                        replyError = 'Unknown check_idle error';
                        if isfield(reply,'error')
                            replyError = app.jsonValueToText(reply.error);
                        end
                        success = false;
                        errMsg = ['check_idle error: ',replyError];
                        return;
                    end
                    if ~strcmp(replyStatus,'ready')
                        success = false;
                        errMsg = ['Unexpected check_idle status: ',replyStatus];
                        return;
                    end

                    isIdle = false;
                    if isfield(reply,'idle')
                        isIdle = logical(reply.idle);
                    end

                    if isIdle
                        idleReason = '';
                        if isfield(reply,'reason')
                            idleReason = app.jsonValueToText(reply.reason);
                        end
                        if isempty(idleReason)
                            app.debugMessage('Photostim reported idle.');
                        else
                            app.debugMessage(['Photostim reported idle: ',idleReason]);
                        end
                        return;
                    end

                    if toc(startTime) > timeoutPeriod
                        success = false;
                        errMsg = 'Timed out waiting for photostim to become idle.';
                        return;
                    end

                    nextPollDelay = pollInterval;
                    if isfield(reply,'expected_idle_after_s')
                        expectedIdleAfter = app.jsonValueToDouble(reply.expected_idle_after_s);
                        if ~isnan(expectedIdleAfter) && isfinite(expectedIdleAfter) && expectedIdleAfter > 0
                            nextPollDelay = expectedIdleAfter + 0.05;
                            app.debugMessage(['Waiting for opto_2p to completed (expected in ',num2str(expectedIdleAfter),' secs)']);
                        end
                    end

                    pause(nextPollDelay);
                end
            catch err
                success = false;
                errMsg = ['check_idle failed: ',err.message];
            end
        end

        function restoreRunButton(app)
            app.RunButton.Enable = "on";
            app.RunButton.Text = 'Run';
            app.RunButton.BackgroundColor = 'g';
            app.abortFlag = 0;
            app.photoStimStarted = false;
        end

        function requestRunAbort(app, messageText)
            app.abortFlag = 1;
            app.RunButton.Text = 'Aborting...';
            app.RunButton.Enable = "off";
            if nargin > 1 && ~isempty(messageText)
                app.debugMessage(messageText);
            end
        end

        function udpSocket = createUdpSocket(app, timeoutPeriod, localPort)
            if nargin < 4 || isempty(localPort)
                udpSocket = udpport("datagram", "IPV4");
            else
                udpSocket = udpport("datagram", "IPV4", "LocalPort", localPort);
            end
            udpSocket.Timeout = timeoutPeriod;
        end

        function writeUdpPayload(app, udpSocket, remoteHost, remotePort, payloadBytes)
            if isstring(payloadBytes)
                payloadBytes = unicode2native(char(payloadBytes), 'UTF-8');
            elseif ischar(payloadBytes)
                payloadBytes = unicode2native(payloadBytes, 'UTF-8');
            else
                payloadBytes = uint8(payloadBytes);
            end
            write(udpSocket, payloadBytes, "uint8", remoteHost, remotePort);
        end

        function response = waitForUdpBytes(app, udpSocket, timeoutPeriod, processEvents)
            if nargin < 5
                processEvents = true;
            end

            response = [];
            startTime = tic;
            while udpSocket.NumDatagramsAvailable == 0
                if toc(startTime) > timeoutPeriod
                    return;
                end
                if processEvents
                    drawnow limitrate;
                end
                pause(0.05);
            end

            response = app.readSingleUdpDatagram(udpSocket);
        end

        function response = readSingleUdpDatagram(app, udpSocket)
            response = read(udpSocket, 1, 'uint8');
            response = app.normalizeUdpReadData(response);
        end

        function payloadBytes = normalizeUdpReadData(app, rawData)
            if isobject(rawData)
                if isprop(rawData, 'Data')
                    rawData = rawData.Data;
                elseif numel(rawData) == 1
                    try
                        rawData = rawData.Data;
                    catch
                        error('Unsupported udpport datagram object returned by read().');
                    end
                else
                    error('Unsupported multi-datagram object returned by read().');
                end
            elseif isstruct(rawData) && isfield(rawData, 'Data')
                rawData = rawData.Data;
            end

            payloadBytes = uint8(rawData(:)');
        end

        function cleanupUdpSocket(app, udpSocket)
            if isempty(udpSocket)
                return;
            end
            try
                configureCallback(udpSocket, "off");
            catch
            end
            try
                clear udpSocket;
            catch
            end
        end

        function ensureBvDataInitialized(app)
            global bvData;

            if ~isstruct(bvData)
                bvData = struct();
            end
            if ~isfield(bvData,'expData') || ~isstruct(bvData.expData)
                bvData.expData = struct();
            end
            if ~isfield(bvData.expData,'stims') || ~isstruct(bvData.expData.stims)
                bvData.expData.stims = struct('features', {}, 'reps', {});
            end
            if ~isfield(bvData,'settings') || ~isstruct(bvData.settings)
                bvData.settings = struct();
            end
        end

        function [reply, responseText, success] = decodeUdpJsonReply(app, response)
            reply = struct();
            success = false;
            rawResponseText = native2unicode(uint8(response)','UTF-8');
            if isstring(rawResponseText)
                rawResponseText = char(rawResponseText);
            elseif ~ischar(rawResponseText)
                rawResponseText = char(rawResponseText);
            end
            rawResponseText = rawResponseText(:)';
            responseText = strtrim(rawResponseText);

            try
                reply = jsondecode(responseText);
                success = true;
                return;
            catch
            end

            responseChars = double(rawResponseText);
            keepMask = responseChars >= 32 | responseChars == 9 | responseChars == 10 | responseChars == 13;
            responseText = rawResponseText(keepMask);
            responseText = strtrim(responseText);
            responseText = responseText(:)';

            firstBrace = find(responseText == '{', 1, 'first');
            lastBrace = find(responseText == '}', 1, 'last');
            if isempty(firstBrace) || isempty(lastBrace) || lastBrace < firstBrace
                return;
            end

            responseText = responseText(firstBrace:lastBrace);
            responseText = responseText(:)';

            try
                reply = jsondecode(responseText);
                success = true;
            catch
                fprintf(1,'UDP JSON decode failed.\n');
                fprintf(1,'RAW_REPLY_BEGIN\n%s\nRAW_REPLY_END\n', rawResponseText);
                fprintf(1,'CLEAN_REPLY_BEGIN\n%s\nCLEAN_REPLY_END\n', responseText);
                success = false;
            end
        end

        function textValue = jsonValueToText(app, rawValue)
            if isstring(rawValue)
                textValue = char(join(rawValue, ", "));
            elseif ischar(rawValue)
                textValue = rawValue;
            elseif isnumeric(rawValue) || islogical(rawValue)
                if isscalar(rawValue)
                    textValue = num2str(rawValue);
                else
                    textValue = strjoin(arrayfun(@num2str, rawValue(:)', 'UniformOutput', false), ', ');
                end
            elseif iscell(rawValue)
                textValue = strjoin(cellfun(@(x) app.jsonValueToText(x), rawValue, 'UniformOutput', false), ', ');
            else
                try
                    textValue = char(jsonencode(rawValue));
                catch
                    textValue = strtrim(evalc('disp(rawValue)'));
                end
            end
        end

        function textValues = jsonValueToTextCell(app, rawValue)
            if iscell(rawValue)
                textValues = cellfun(@(x) app.jsonValueToText(x), rawValue, 'UniformOutput', false);
            elseif isstring(rawValue)
                textValues = cellstr(rawValue(:));
            elseif ischar(rawValue)
                textValues = {rawValue};
            elseif isnumeric(rawValue) || islogical(rawValue)
                textValues = arrayfun(@num2str, rawValue(:)', 'UniformOutput', false);
            else
                textValues = {app.jsonValueToText(rawValue)};
            end
        end

        function numericValue = jsonValueToDouble(app, rawValue)
            if isnumeric(rawValue) || islogical(rawValue)
                numericValue = double(rawValue);
                if ~isscalar(numericValue)
                    numericValue = numericValue(1);
                end
            else
                numericValue = str2double(app.jsonValueToText(rawValue));
            end
        end


        function results = bvUpdateGUI(app)
            % function to make the table in the gui reflect the currently
            % loaded experiment data structure
            global bvData;
            app.ensureBvDataInitialized();

            if isfield(bvData.expData,'vars')
                app.VariablesEditField.Value = bvData.expData.vars;
            else
                app.VariablesEditField.Value = '';
            end

            if isfield(bvData.expData,'iti')
                app.ITIEditField.Value = bvData.expData.iti;
            else
                app.ITIEditField.Value = '1';
            end

            if isfield(bvData.expData,'seqreps')
                app.SequenceRepeatsEditField.Value = bvData.expData.seqreps;
            else
                app.SequenceRepeatsEditField.Value = '10';
            end

            if isfield(bvData.expData,'pauseafterpreload')
                app.PauseafterpreloadEditField.Value = bvData.expData.pauseafterpreload;
            else
                app.PauseafterpreloadEditField.Value = '5';
            end

            % update list of stims
            app.StimulusListBox.Items = {};
            for iStim = 1:length(bvData.expData.stims)
                app.StimulusListBox.Items = [app.StimulusListBox.Items,{num2str(iStim)}];
            end

            if ~isempty(app.StimulusListBox.Tag)
                app.StimulusListBox.Value = app.StimulusListBox.Tag;
                app.RepsEditField.Value = num2str(bvData.expData.stims(str2num(app.StimulusListBox.Tag)).reps);
            end

            % get current selection of stim to update list of features
            currentStim = str2num(app.StimulusListBox.Tag);
            if isempty(currentStim)
                % make feature list empty
                app.FeatureListBox.Items = {};
                % make parameter table empty
                app.UITable.Data = [];
                app.UITable.ColumnName = {'Nothing selected'};
                return;
            end
            % update list of features in current stim
            app.FeatureListBox.Items = {};
            for iFeat = 1:length(bvData.expData.stims(currentStim).features)
                app.FeatureListBox.Items = [app.FeatureListBox.Items,{[num2str(iFeat),'-',bvData.expData.stims(currentStim).features(iFeat).name{1}]}];
            end
            app.FeatureListBox.ItemsData = 1:length(bvData.expData.stims(currentStim).features);

            % get current selection of feature to update list of properties
            currentFeat = str2num(app.FeatureListBox.Tag);
            if isempty(currentFeat)
                % make parameter table empty
                app.UITable.Data = [];
                app.UITable.ColumnName = {'Nothing selected'};
                return;
            end
            app.FeatureListBox.Value = currentFeat;
            paramNames = bvData.expData.stims(currentStim).features(currentFeat).params;
            paramVals = bvData.expData.stims(currentStim).features(currentFeat).vals;
            x = table(paramVals');
            app.UITable.Data = x; %table(paramVals',"RowNames",paramNames);
            app.UITable.RowName = paramNames;
            app.UITable.ColumnName = bvData.expData.stims(currentStim).features(currentFeat).name;

        end

        function results = debugMessage(app,outputMsg)
            currentTime = datestr(datetime('now','TimeZone','local','Format','HH:mm:ss'));
            currentTime = currentTime(end-7:end);
            outputMsg = [currentTime,': ',outputMsg];
            app.FeedbackListBox.Items(end+1)={outputMsg};
            drawnow
            app.FeedbackListBox.Value = outputMsg;
            app.FeedbackListBox.scroll('bottom')
            %       if length(app.FeedbackListBox.Items)>30
            %         app.FeedbackListBox.Items(1:length(app.FeedbackListBox.Items)-30)=[];
            %       end
        end

        function schemaDir = getDefaultOptoSchemaDir(app)
            schemaDir = '\\AR-LAB-NAS1\DataServer\opto_schemas';
        end

        function loadExperimentDataIntoGui(app, expData, stimFilename)
            global bvData;
            app.ensureBvDataInitialized();
            bvData.expData = expData;
            if nargin >= 3
                bvData.stim_filename = stimFilename;
            end
            app.StimulusListBox.Tag = '';
            app.FeatureListBox.Tag = '';
            app.FeatureListBox.Value = {};
            app.bvUpdateGUI;
        end

        function [experimentDescription, experimentUser, cancelled] = promptExperimentStartInfo(app, defaultDescription)
            users = {'adamranson','albertestop','joaoribeiros','rubencorreia','yannickbollmann'};
            experimentDescription = '';
            experimentUser = '';
            cancelled = true;
            if isempty(app.lastExperimentUser)
                dropdownUsers = [{''}, users];
                selectedUserIndex = 1;
            else
                dropdownUsers = users;
                selectedUserIndex = find(strcmp(users, app.lastExperimentUser), 1);
                if isempty(selectedUserIndex)
                    dropdownUsers = [{''}, users];
                    selectedUserIndex = 1;
                end
            end

            dlg = dialog('Name','Experiment info','WindowStyle','modal','Visible','off','Position',[100 100 380 170]);
            movegui(dlg,'center');
            uicontrol('Parent',dlg,'Style','text','String','Description','HorizontalAlignment','left','Position',[20 120 100 20]);
            descriptionEdit = uicontrol('Parent',dlg,'Style','edit','String',defaultDescription,'HorizontalAlignment','left','Position',[125 120 235 24]);
            uicontrol('Parent',dlg,'Style','text','String','User','HorizontalAlignment','left','Position',[20 80 100 20]);
            userPopup = uicontrol('Parent',dlg,'Style','popupmenu','String',dropdownUsers,'Value',selectedUserIndex,'Position',[125 80 235 24]);
            uicontrol('Parent',dlg,'Style','pushbutton','String','OK','Position',[185 25 80 28],'Callback',@okCallback);
            uicontrol('Parent',dlg,'Style','pushbutton','String','Cancel','Position',[280 25 80 28],'Callback',@cancelCallback);
            dlg.CloseRequestFcn = @cancelCallback;
            dlg.Visible = 'on';
            uiwait(dlg);

            function okCallback(~,~)
                experimentDescription = char(descriptionEdit.String);
                selectedUser = dropdownUsers{userPopup.Value};
                if isempty(strtrim(selectedUser))
                    msgbox('Please select a user before starting the experiment.','User required','warn');
                    return;
                end
                experimentUser = selectedUser;
                app.lastExperimentUser = experimentUser;
                cancelled = false;
                delete(dlg);
            end

            function cancelCallback(~,~)
                cancelled = true;
                delete(dlg);
            end
        end

        function [success, errMsg] = writeExperimentMetadata(app, expSavePath, expID, animalID, experimentUser, experimentDescription, stimFilename)
            success = true;
            errMsg = '';
            metadata = struct( ...
                'expID', expID, ...
                'animalID', animalID, ...
                'user', experimentUser, ...
                'description', experimentDescription, ...
                'stim_filename', stimFilename, ...
                'remote_experiment_dir', expSavePath, ...
                'created_at', char(datetime('now','TimeZone','local','Format','yyyy-MM-dd HH:mm:ss Z')));

            metadataPath = fullfile(expSavePath, [expID,'_experiment_metadata.json']);
            [fileId, fileErr] = fopen(metadataPath, 'w');
            if fileId < 0
                success = false;
                errMsg = ['Could not write experiment metadata to ',metadataPath,': ',fileErr];
                return;
            end
            fwrite(fileId, jsonencode(metadata), 'char');
            fwrite(fileId, newline, 'char');
            fclose(fileId);
            app.debugMessage(['Saved experiment metadata to ',metadataPath]);
        end

        function [finalComment, discardExperiment] = promptFinalComment(app)
            finalComment = '';
            discardExperiment = false;

            dlg = dialog('Name','Experiment complete','WindowStyle','modal','Visible','off','Position',[100 100 420 155]);
            movegui(dlg,'center');
            uicontrol('Parent',dlg,'Style','text','String','Final comments','HorizontalAlignment','left','Position',[20 105 100 20]);
            commentEdit = uicontrol('Parent',dlg,'Style','edit','String','','HorizontalAlignment','left','Position',[125 105 275 24]);
            uicontrol('Parent',dlg,'Style','pushbutton','String','Save','Position',[130 35 80 30],'Callback',@saveCallback);
            uicontrol('Parent',dlg,'Style','pushbutton','String','Discard','Position',[220 35 80 30],'Callback',@discardCallback);
            uicontrol('Parent',dlg,'Style','pushbutton','String','Cancel','Position',[310 35 80 30],'Callback',@cancelCallback);
            dlg.CloseRequestFcn = @cancelCallback;
            dlg.Visible = 'on';
            uicontrol(commentEdit);
            uiwait(dlg);

            function saveCallback(~,~)
                finalComment = char(commentEdit.String);
                discardExperiment = strcmpi(strtrim(finalComment),'d');
                delete(dlg);
            end

            function discardCallback(~,~)
                confirmation = confirmDiscard();
                if strcmp(confirmation,'Discard')
                    finalComment = char(commentEdit.String);
                    discardExperiment = true;
                    delete(dlg);
                end
            end

            function choice = confirmDiscard()
                choice = 'Keep experiment';
                confirmDlg = dialog('Name','Confirm discard','WindowStyle','modal','Visible','off','Position',[100 100 360 135]);
                movegui(confirmDlg,'center');
                uicontrol('Parent',confirmDlg,'Style','text','String','Do you want to discard this experiment?','HorizontalAlignment','left','Position',[25 80 310 22]);
                uicontrol('Parent',confirmDlg,'Style','pushbutton','String','Discard','Position',[105 25 90 30],'Callback',@confirmDiscardCallback);
                uicontrol('Parent',confirmDlg,'Style','pushbutton','String','Keep experiment','Position',[205 25 125 30],'Callback',@keepExperimentCallback);
                confirmDlg.CloseRequestFcn = @keepExperimentCallback;
                confirmDlg.Visible = 'on';
                uiwait(confirmDlg);
                if isvalid(confirmDlg)
                    choice = confirmDlg.UserData;
                    delete(confirmDlg);
                end

                function confirmDiscardCallback(~,~)
                    confirmDlg.UserData = 'Discard';
                    uiresume(confirmDlg);
                end

                function keepExperimentCallback(~,~)
                    confirmDlg.UserData = 'Keep experiment';
                    uiresume(confirmDlg);
                end
            end

            function cancelCallback(~,~)
                delete(dlg);
            end
        end

        function markExperimentForDeletion(app, expSavePath)
            markerPath = fullfile(expSavePath,'deleteme');
            [fileId, fileErr] = fopen(markerPath,'w');
            if fileId < 0
                app.debugMessage(['Could not create discard marker ',markerPath,': ',fileErr]);
                return;
            end
            fclose(fileId);
            app.debugMessage(['Marked experiment for deletion: ',markerPath]);
        end

        function saveForPython(app,expDat,expID,save_path)
            Folder = save_path;
            % Folder = 'G:\.shortcut-targets-by-id\1P7g8LSE5D6vInT7OOXY1EIzJ0M4zvhos\Remote_Repository\TEST\2023-02-27_09_TEST';
            FileList = dir(fullfile(Folder, '**', '*stim.mat'));
            disp(['Found ',num2str(length(FileList)),' stim files']);
            for iFile = 1:length(FileList)
                % load matlab stim file
                load(fullfile(FileList(iFile).folder,FileList(iFile).name));
                disp(['Starting ',expDat.expID,'(',num2str(iFile),'/',num2str(length(FileList)),')...']);
                % determine max number of feats
                max_feat = 0;
                for iStim = 1:length(expDat.stims)
                    if length(expDat.stims(iStim).features)>max_feat
                        max_feat = length(expDat.stims(iStim).features);
                    end
                end
                % determine the total stimulus duration by finding the max start +
                % duration value
                allDurations = zeros([length(expDat.stims),1]);
                for iStim = 1:length(expDat.stims)
                    for iFeat = 1:length(expDat.stims(iStim).features)
                        [~,startIdx] = ismember('onset',expDat.stims(iStim).features(iFeat).params);
                        [~,durationIdx] = ismember('duration',expDat.stims(iStim).features(iFeat).params);
                        if startIdx > 0 && durationIdx > 0
                          if str2num(expDat.stims(iStim).features(iFeat).vals{startIdx})+str2num(expDat.stims(iStim).features(iFeat).vals{durationIdx}) > allDurations(iStim)
                              allDurations(iStim) =  str2num(expDat.stims(iStim).features(iFeat).vals{startIdx})+str2num(expDat.stims(iStim).features(iFeat).vals{durationIdx});
                          end
                        end
                    end
                end
                % make empty variable for param names
                for iFeat = 1:max_feat
                    all_param_names{iFeat} = {};
                end
                % for each feature number discover all unique parameter names
                for iStim = 1:length(expDat.stims)
                    for iFeat = 1:length(expDat.stims(iStim).features)
                        all_param_names{iFeat} = cat(2,all_param_names{iFeat},expDat.stims(iStim).features(iFeat).params,'type');
                    end
                end
                % find unique param names for each feature
                for iFeat = 1:max_feat
                    all_param_names{iFeat} = unique(all_param_names{iFeat});
                end
                % build a table for csv output for each feature
                output_table = [];
                for iStim = 1:length(expDat.stims)
                    for iFeat = 1:max_feat
                        % check stim has enough features to probe iFeat
                        if length(expDat.stims(iStim).features)>=iFeat
                            for iParam = 1:length(all_param_names{iFeat})
                                % find if the param name exists in the stim/feature
                                [Lia,Locb] = ismember(all_param_names{iFeat}{iParam},expDat.stims(iStim).features(iFeat).params);
                                if Lia
                                    output_table{iFeat}(iStim,iParam) = {expDat.stims(iStim).features(iFeat).vals{Locb}};
                                else
                                    if strcmp(all_param_names{iFeat}{iParam},'type')
                                        output_table{iFeat}(iStim,iParam) = {expDat.stims(iStim).features(iFeat).name};
                                    else
                                        output_table{iFeat}(iStim,iParam) = {'NaN'};
                                    end
                                end
                            end
                        else
                            % pad with nans
                            output_table{iFeat}(iStim,1:length(all_param_names{iFeat})) = {'NaN'};
                        end
                    end
                end
                % combine the feature tables into 1 table
                combined_output_table = [];
                for iFeat = 1:max_feat
                    combined_output_table = [combined_output_table,output_table{iFeat}];
                end
                % make a table where each row is a trial
                combined_output_table_all_trials = [];
                for iStim = 1:length(expDat.stimOrder)
                    combined_output_table_all_trials = cat(1,combined_output_table_all_trials,combined_output_table(expDat.stimOrder(iStim),:));
                end
                % rename params to append feat number in headers
                param_header = [];
                for iFeat = 1:max_feat
                    all_feat_headers = [];
                    for iParam = 1:length(all_param_names{iFeat})
                        all_feat_headers{iParam} = ['F',num2str(iFeat),'_',all_param_names{iFeat}{iParam}];
                    end
                    param_header = [param_header,all_feat_headers];
                end
                % add column for stim max length
                param_table = cell2table(cat(2,cellstr(num2str(allDurations)),combined_output_table), 'VariableNames',cat(2,{'duration'},param_header));
                % next 4 lines is to deal with case where there is only 1 stim
                % conditions which causes all_durs to be oriented wrong
                all_durs = allDurations(expDat.stimOrder);
                if size(all_durs,2)>size(all_durs,1)
                    all_durs = all_durs';
                end
                all_trials_table = cell2table(cat(2,cellstr(num2str(expDat.stimOrder')),cellstr(num2str(all_durs)),combined_output_table_all_trials),'VariableNames',cat(2,{'stim'},{'duration'},param_header));
                % save as csv
                save_path = FileList(iFile).folder;
                expID = expDat.expID;
                writematrix(expDat.stimOrder',fullfile(save_path,[expID,'_stim_order.csv']));
                writetable(param_table,fullfile(save_path,[expID,'_stim.csv']));
                writetable(all_trials_table,fullfile(save_path,[expID,'_all_trials.csv']));
                disp(['Finished ',expDat.expID]);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global bvData;
            bvData = struct();
            app.ensureBvDataInitialized();
            try
                config = app.getRepoConfig();
            catch err
                repoRoot = app.getRepoRoot();
                machineName = app.getMachineName();
                missingIni = fullfile(repoRoot,'configs',machineName,'bvGUI.ini');
                app.debugMessage(['Repo root: ', repoRoot]);
                app.debugMessage(['Machine config key: ', machineName]);
                app.debugMessage(['Config error: ', err.message]);
                app.debugMessage(['Expected config file: ', missingIni]);
                return;
            end
            app.addRepoSupportPaths(config.repoRoot);
            app.debugMessage(['Repo root: ', config.repoRoot]);
            app.debugMessage(['Machine config key: ', config.machineName]);
            app.debugMessage(['Config file: ', config.iniPath]);
            % load settings
            bvData.settings = struct();
            bvData.settings.bvServer = config.bvServer;
            app.BVServerEditField.Value = bvData.settings.bvServer;
            % populate new feature list box with feature files
            % find feature files
            featFilePath = config.featuresDir;
            featFiles = dir(fullfile(featFilePath,'*.mat'));
            featFiles = {featFiles.name};
            app.NewFeatureListBox.Items = {};
            for iFile = 1:length(featFiles)
                load(fullfile(featFilePath,featFiles{iFile}))
                bvData.featTypes(iFile) = featureType;
                % add feature file names to list box
                [~,featName] = fileparts(featFiles{iFile});
                app.NewFeatureListBox.Items = [app.NewFeatureListBox.Items,{featName}];
                bvData.featNames(iFile) = {featName};
            end
            app.NewFeatureListBox.ItemsData = 1:length(featFiles);

            % populate list of DAQ devices that can be started
            daqList = dir(fullfile(config.daqStartDir,'*.m'));
            daqList = {daqList.name}';
            for iDaq = 1:length(daqList)
                daqList{iDaq} = daqList{iDaq}(1:end-8);
            end
            x=table();
            x.daqs = daqList;
            x.enabled = ones([length(x.daqs),1]);
            app.UITable_DAQs.Data = x;
            app.UITable_DAQs.RowName = [];
            app.UITable_DAQs.ColumnName = {'DAQ','Enabled'};
            bvData.plotAreas = figure('Name','Online analysis','NumberTitle','off','MenuBar', 'None');
            bvData.plotAreas.Position=[app.UIFigure.Position(1)+app.UIFigure.Position(3)+30,app.UIFigure.Position(2),500,app.UIFigure.Position(4)];
            bvData.UIFigure = app.UIFigure;
            bvData.stim_filename = '';
        end

        % Value changed function: FeatureListBox
        function FeatureListBoxValueChanged(app, event)
            value = app.FeatureListBox.Value;
            app.FeatureListBox.Tag = num2str(value);
            app.bvUpdateGUI();
            % app.UITable.
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            % create empty experiment structure
            global bvData;
            app.ensureBvDataInitialized();
            bvData.expData = struct();
            bvData.expData.stims = struct('features', {}, 'reps', {});
            app.StimulusListBox.Tag = '';
            app.FeatureListBox.Tag = '';
            app.FeatureListBox.Value = {};
            app.bvUpdateGUI();
        end

        % Button pushed function: ButtonAddStim
        function ButtonAddStimPushed(app, event)
            global bvData;
            app.ensureBvDataInitialized();
            bvData.expData.stims(end+1).features = [];
            bvData.expData.stims(end).reps = str2num(app.RepsEditField.Value);
            app.StimulusListBox.Tag = num2str(length(bvData.expData.stims));
            app.FeatureListBox.Tag = '';
            app.bvUpdateGUI;
        end

        % Button pushed function: ButtonAddFeature
        function ButtonAddFeaturePushed(app, event)
            global bvData;
            app.ensureBvDataInitialized();
            % determine current selected new feature to add
            currentNewFeat = str2num(app.NewFeatureListBox.Tag);
            currentStim = str2num(app.StimulusListBox.Tag);
            if ~isempty(currentNewFeat) && ~isempty(currentStim)
                newFeatureID = length(bvData.expData.stims(currentStim).features)+1;
                bvData.expData.stims(currentStim).features(newFeatureID).vals = bvData.featTypes(currentNewFeat).vals;
                bvData.expData.stims(currentStim).features(newFeatureID).params = bvData.featTypes(currentNewFeat).params;
                bvData.expData.stims(currentStim).features(newFeatureID).name = bvData.featNames(currentNewFeat);
                app.FeatureListBox.Tag = num2str(newFeatureID);
                app.bvUpdateGUI;
            end
        end

        % Value changed function: StimulusListBox
        function StimulusListBoxValueChanged(app, event)
            value = app.StimulusListBox.Value;
            [~,value] = ismember(value,app.StimulusListBox.Items);
            app.StimulusListBox.Tag = num2str(value);
            app.FeatureListBox.Tag = '';
            app.bvUpdateGUI;
        end

        % Value changed function: NewFeatureListBox
        function NewFeatureListBoxValueChanged(app, event)
            value = app.NewFeatureListBox.Value;
            app.NewFeatureListBox.Tag = num2str(value);
        end

        % Button pushed function: ButtonRemoveStim
        function ButtonRemoveStimPushed(app, event)
            global bvData;
            stimsToPreserve = setdiff(1:length(bvData.expData.stims),str2num(app.StimulusListBox.Tag));
            bvData.expData.stims = bvData.expData.stims(stimsToPreserve);
            app.StimulusListBox.Tag = '';
            app.StimulusListBox.Value = {};
            app.bvUpdateGUI;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            global bvData;
            indices = event.Indices;
            newData = event.NewData;
            currentStim = str2num(app.StimulusListBox.Tag);
            currentFeat = str2num(app.FeatureListBox.Tag);
            paramVals = app.UITable.Data.Var1;
            bvData.expData.stims(currentStim).features(currentFeat).vals = paramVals';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global bvData;
            config = app.getRepoConfig();
            expData = bvData.expData;
            expData.vars = app.VariablesEditField.Value;
            expData.iti = app.ITIEditField.Value;
            expData.seqreps = app.SequenceRepeatsEditField.Value;
            expData.pauseafterpreload = app.PauseafterpreloadEditField.Value;
            uisave({'expData'},config.stimsetsDir);
            figure(app.UIFigure);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global bvData;
            config = app.getRepoConfig();
            [file,path] = uigetfile(config.stimsetsDir);
            if isequal(file,0)
                return
            end
            load(fullfile(path,file));
            figure(app.UIFigure);
            app.UIFigure.Visible = 'on';
            if exist('expData','var')
                [~,bvData.stim_filename,~] = fileparts(file);
                app.loadExperimentDataIntoGui(expData, bvData.stim_filename);
            end
        end

        % Button pushed function: ImportSchemaButton
        function ImportSchemaButtonPushed(app, event)
            schemaDir = app.getDefaultOptoSchemaDir();
            [file,path] = uigetfile({'schema.yaml;schema.yml;*.yaml;*.yml', 'Opto schema YAML (*.yaml, *.yml)'}, 'Select opto schema', schemaDir);
            figure(app.UIFigure);
            app.UIFigure.Visible = 'on';
            if isequal(file,0)
                return
            end

            schemaPath = fullfile(path,file);
            try
                expData = build_bvgui_config_from_opto_schema(schemaPath);
            catch err
                app.debugMessage(['Schema import failed: ',err.message]);
                return
            end

            schemaFolder = fileparts(schemaPath);
            [~,schemaName] = fileparts(schemaFolder);
            app.loadExperimentDataIntoGui(expData, [schemaName,'_bvgui']);
            app.debugMessage(['Imported opto schema ',schemaName,' with ',num2str(length(expData.stims)),' stimulus/stimuli']);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % check if we want to run or abort depending on button label
            % value
            switch app.RunButton.Text
                case 'Abort'
                    % then we are already running so set abort flag to true
                    app.abortFlag = 1;
                    app.RunButton.Text = 'Aborting...';
                    app.RunButton.Enable = "off";
                    debugMessage(app,'Aborting...');
                    return;
                case 'Aborting'
                    % don't need to do anything more
                    return;
                case 'Run'
                    % allow run to initiate
                    app.RunButton.Text = 'Abort';
                    app.RunButton.BackgroundColor = 'r';
            end

            global bvData;
            config = app.getRepoConfig();
            remotePath = config.remoteSaveRoot;
            animalIDInput = strtrim(app.AnimalIDEditField.Value);
            if ~strcmp(animalIDInput,'TEST') && isempty(regexp(animalIDInput,'^ES[A-Z]{2}[0-9]{3}$','once'))
                errMsg = ['Invalid animal ID: ',animalIDInput,'. Use TEST or ES followed by two capital letters and three numbers, e.g. ESAB123.'];
                app.debugMessage(errMsg);
                msgbox(errMsg,'Invalid animal ID','error');
                app.restoreRunButton();
                return;
            end
            app.AnimalIDEditField.Value = animalIDInput;
            % prompt before creating an expID or experiment folders
            [experimentDescription, experimentUser, metadataCancelled] = app.promptExperimentStartInfo(bvData.stim_filename);
            if metadataCancelled
                app.debugMessage('Experiment start cancelled.');
                app.restoreRunButton();
                return;
            end
            % get a new unique animal ID
            expID = newExpID(animalIDInput);
            animalID = expID(15:end);
            bvSavePath = fullfile(config.localSaveRoot,animalID,expID);
            bvSavePath = strrep(bvSavePath,'\','/');
            savePath = remotePath;
            expSavePath = fullfile(savePath,animalID,expID);
            app.currentExperimentLogPath = fullfile(expSavePath,'exp_log.txt');
            if ~exist(expSavePath,'dir')
                mkdir(expSavePath);
            end
            app.debugMessage('==========================');
            app.debugMessage(['Starting new experiment - ',expID]);
            % request BV computer to make exp dir
            % bv_address = string(bv_config.bvGUIsettings.bvServer);
            bv_address = app.BVServerEditField.Value;
            bv_udp_port = 64645;
            msg = "mkdir" + " " + bvSavePath;
            [response, responseDetail] = app.send_udp_command(bv_address, bv_udp_port, msg, 30);
            if response == 1
                app.debugMessage('Make data folder command succeeded.');
            elseif response == -1
                app.debugMessage(['Make data folder command failed. host=', char(bv_address), ' port=', num2str(bv_udp_port), ' cmd=', char(msg), ' detail=', app.jsonValueToText(responseDetail)]);
                app.restoreRunButton();
                return;
            else
                app.debugMessage(['Unexpected server response while creating data folder. detail=', app.jsonValueToText(responseDetail)]);
                app.restoreRunButton();
                return;
            end         
            % clear the log box and save experiment metadata
            app.ExperimentlogTextArea.Value = {datestr(datetime),expID,['User: ',experimentUser],experimentDescription,''};
            [metadataSaved, metadataErr] = app.writeExperimentMetadata(expSavePath, expID, animalID, experimentUser, experimentDescription, bvData.stim_filename);
            if ~metadataSaved
                app.debugMessage(metadataErr);
                app.restoreRunButton();
                return;
            end

            completeStimSeq = app.buildCompleteStimSeq();
            % make sure bonvision server is in idle state 158.109.215.49
            try
              u = OscTcp(bv_address, 4002);
              rig = Rig(u);
              % send dummy stim to initialise the sync square
              % can be removed if Goncalo fixes first trial bug
              % =========
%               try
%                 rmdir('\\AR-LAB-NAS1\DataServer\Remote_Repository\TEST\2025-01-01_01_TEST', 's');
%               catch 
%               end
%               dummy_root = '\\AR-LAB-NAS1\DataServer\Remote_Repository';
%               dummy_data = '\\AR-LAB-NAS1\DataServer\Remote_Repository\TEST\2025-01-01_01_TEST';
%               mkdir(dummy_data)
%               rig.dataset(dummy_root);
%               rig.experiment('2025-01-01_01_TEST')
%               g = [];
%               g.contrast = 1;
%               g.duration = 0.1;
%               g.y = -100;
%               % add grating to rig
%               rig.gratings(g);
%               rig.start();
%               datagram = [];
%               while(isempty(datagram))
%                 datagram = u.receive();
%                 drawnow limitrate;
%               end
              % ======

              % ensure rig is cleared
              rig = Rig(u);
              rig.clear();
              rig.experiment('');
              app.pauseWithEvents(1);
            catch
              % bonvision server probably not in use
              app.debugMessage('Problem clearing Bonvision')
              app.restoreRunButton();
              debugMessage(app,['Experiment complete with error - ',expID]);
              app.debugMessage('Check Bonvision server is running')
              return;
            end

            % convert all variable stim params to their values defined in the gui
            allVars = app.VariablesEditField.Value;
            allVars = strsplit(allVars,';');
            varStruc = [];
            for iVar = 1:length(allVars)
                allVars{iVar} = strrep(allVars{iVar},' ','');
                if ~isempty(allVars{iVar})
                    eval(['varStruc.',allVars{iVar}]);
                end
            end

            expDataEval = bvData.expData.stims;
            for iStim = 1:length(expDataEval)
                for iFeature = 1:length(expDataEval(iStim).features)
                    featureParamsCell = expDataEval(iStim).features(iFeature).params;
                    featureParams = cell2struct(expDataEval(iStim).features(iFeature).vals',expDataEval(iStim).features(iFeature).params);
                    for iParam = 1:length(featureParamsCell)
                        if isfield(varStruc,featureParams.(featureParamsCell{iParam}))
                            variableVal = varStruc.(featureParams.(featureParamsCell{iParam}));
                            if ~isstring(variableVal)
                                variableVal = num2str(variableVal);
                            end
                            expDataEval(iStim).features(iFeature).vals{iParam} = variableVal;
                        end
                    end
                end
            end

            [stimulusConditions, conditionIndexByStimIdx, conditionCatalogErr] = app.buildStimulusConditionCatalog(expDataEval, completeStimSeq);
            if ~isempty(conditionCatalogErr)
                app.debugMessage(conditionCatalogErr);
                app.restoreRunButton();
                return;
            end
            trialConditionIndices = conditionIndexByStimIdx(completeStimSeq);
            [success, updateParamsErr] = app.sendExperimentParams(config, expID, stimulusConditions, trialConditionIndices, expSavePath);
            if ~success
                app.debugMessage(updateParamsErr);
                app.restoreRunButton();
                return;
            end

            [opto2pPrep, opto2pErr] = app.collectOpto2pPrepData(expDataEval, completeStimSeq);
            if ~isempty(opto2pErr)
                app.debugMessage(['Opto_2p prep error: ', opto2pErr]);
                app.restoreRunButton();
                return;
            end

            if opto2pPrep.enabled
                [success, opto2pErr] = app.runOpto2pPrep(config, expID, opto2pPrep);
                if ~success
                    app.debugMessage(opto2pErr);
                    app.restoreRunButton();
                    return;
                end
            end
            % initiate any DAQ devices which have been selected
            daqList = dir(fullfile(config.daqStartDir,'*.m'));
            daqList = {daqList.name}';
            % do checking to make sure all start and stop daqs match up
            % ... to implement

            % pull data from table of whether DAQ devices are enabled
            daqEnabled = app.UITable_DAQs.Data.enabled;

            startDir = cd;
            err_msg = '';
            cd(config.daqStartDir);
            app.debugMessage('Attempting to start all DAQs');
            for iDaq = 1:length(daqList)
                if daqEnabled(iDaq)
                    app.debugMessage(['Running ',daqList{iDaq}]);
                    try
                        [success,resp_msg] = eval([daqList{iDaq}(1:end-2),'(expID)']);
                    catch daqStartErr
                        success = false;
                        resp_msg = daqStartErr.message;
                    end
                    if ~success
                        err_msg = ['Error starting ',daqList{iDaq}];
                        if ~isempty(resp_msg)
                            app.debugMessage(['Start error detail for ',daqList{iDaq},': ',resp_msg]);
                            err_msg = [err_msg,'; ',resp_msg];
                        end
                        app.debugMessage('Error... attempting to stop all DAQs');
                        % attempt to stop all of the daqs in reverse
                        daqList = dir(fullfile(config.daqStopDir,'*.m'));
                        daqList = {daqList.name}';
                        cd(config.daqStopDir);
                        for iDaqStop = iDaq-1:-1:1
                            if daqEnabled(iDaqStop)
                                app.debugMessage(['Stopping ',daqList{iDaqStop}]);
                                try
                                    [success,resp_msg] = eval([daqList{iDaqStop}(1:end-2),'(expID)']);
                                catch daqStopErr
                                    success = false;
                                    resp_msg = daqStopErr.message;
                                end
                                if ~success
                                    app.debugMessage(['Error stopping ',daqList{iDaqStop}]);
                                    if ~isempty(resp_msg)
                                        app.debugMessage(['Stop error detail for ',daqList{iDaqStop},': ',resp_msg]);
                                        err_msg = [err_msg,'; ', ['Error stopping ',daqList{iDaqStop},': ',resp_msg]];
                                    else
                                        err_msg = [err_msg,'; ', ['Error stopping ',daqList{iDaqStop}]];
                                    end
                                else
                                    % app.debugMessage('OK');
                                end
                            end
                        end
                        cd(startDir);
                        % restore GUI elements so ready to start again
                        app.restoreRunButton();
                        debugMessage(app,'Experiment complete (with errors!)');
                        [inp_text, discardExperiment] = app.promptFinalComment();
                        if discardExperiment
                            app.markExperimentForDeletion(expSavePath);
                        end
                        if strcmp(app.ExperimentlogTextArea.Value{end},'')
                            app.ExperimentlogTextArea.Value{end} = inp_text;
                        else
                            app.ExperimentlogTextArea.Value{end+1} = inp_text;
                        end
                        app.ExperimentlogTextArea.Value{end+1} = datestr(datetime);
                        % save log file
                        formatSpec= '%s\r\n';
                        value = app.ExperimentlogTextArea.Value;  % Value entered in textArea
                        % log to experiment log file
                        f = fopen(fullfile(expSavePath,'exp_log.txt'),'w');
                        for i =1:length(value)
                            fprintf(f,formatSpec,value{i});
                        end
                        % append error msg to log
                        fprintf(f,formatSpec,err_msg);
                        fclose(f);

                        return;
                    else
                        % app.debugMessage('OK');
                    end
                end
            end

            % make list of ITIs
            itiText = app.ITIEditField.Value;
            if length(eval(itiText))==1
                % fixed iti
                itiSeq = ones(1,length(completeStimSeq))*eval(itiText);
            else
                % random iti in range
                itiMax = max(eval(itiText));
                itiMin = min(eval(itiText));
                itiSeq = linspace(itiMin,itiMax,length(completeStimSeq));
                itiSeq = itiSeq(randperm(length(itiSeq)));
            end

            preBlankTime = str2num(app.PreBlankText.Value);
            startTime = tic;
            if preBlankTime > 0
                app.debugMessage(['Showing blank for ',num2str(preBlankTime),' secs']);
            end
            while (toc(startTime)<preBlankTime) && app.abortFlag == 0
                % wait
                drawnow limitrate;
            end

            % Pause for 3 secs to allow a check that Bonvision timing pulses are
            % correct (i.e. starting at zero)
            pauseTime = 3;
            startTime = tic;
            while (toc(startTime)<pauseTime) && app.abortFlag == 0
                % wait
                drawnow limitrate;
            end

            if ~isempty(completeStimSeq)
                % establish udp connection
                %global u;
                metadata = expID;
                u = OscTcp(bv_address, 4002);

                % fopen(u);
                rig = Rig(u);
                rig.dataset(strrep(config.localSaveRoot,'\','/'));
                debugMessage(app,'New experiment trigger sent to bonsai');
                % Pause for 10 secs to check what trial ID means in frames file
                % this can probably be removed later:
                pauseTime = 2;
                startTime = tic;
                while (toc(startTime)<pauseTime) && app.abortFlag == 0
                    % wait
                    drawnow limitrate;
                end
%                 % preload all video/image files
%                 all_resources = [];
%                 for iStim = 1:length(expDataEval)
%                     for iFeat = 1:length(expDataEval(iStim).features)
%                         % check if feature is a movie and if so add it as a resource
%                         if strcmp(expDataEval(iStim).features(iFeat).name{1},'movie')
%                             all_resources{end+1} = expDataEval(iStim).features(iFeat).vals{1};
%                         end
%                     end
%                 end
%                 all_resources = unique(all_resources);
%                 for iRes = 1:length(all_resources)
%                     rig.resource(all_resources{iRes});
%                 end
%                 rig.preload();

                % start loop of going through each trial
                % rig.experiment(metadata);
                for iTrial = 1:length(completeStimSeq)
                    drawnow
                    iStim = completeStimSeq(iTrial);
                    [trialOpto2pData, trialOpto2pErr] = app.collectTrialOpto2pData(expDataEval, iStim);
                    if ~isempty(trialOpto2pErr)
                        app.requestRunAbort(['Opto_2p trigger error: ', trialOpto2pErr]);
                        break;
                    end

                    if trialOpto2pData.enabled
                        [success, startTrialErr] = app.startOpto2pTrial(config, conditionIndexByStimIdx(iStim));
                        if ~success
                            app.requestRunAbort(startTrialErr);
                            break;
                        end
                        [success, trialOpto2pErr] = app.triggerOpto2pForTrial(config, expID, trialOpto2pData);
                        if ~success
                            app.requestRunAbort(trialOpto2pErr);
                            break;
                        end
                    end

                    rig.clear();
                    rig.experiment(metadata);
                    debugMessage(app,['Starting trial ',num2str(iTrial),' of ',num2str(length(completeStimSeq))]);
                    go_nogo_trial.enable = false;
                    vr.enable = false;
                    vr.command = '';
                    needsDefaultTriggerGrating = app.trialNeedsDefaultBonvisionTrigger(expDataEval, iStim);
%                     % add synch square high
%                     g = [];
%                     g.angle = 0;
%                     g.width = 10;
%                     g.height = 10;
%                     g.size = 10;
%                     g.x = 0;
%                     g.y = -30;
%                     g.contrast = 1;
%                     g.opacity = 1;
%                     g.phase = 0;
%                     g.freq = 0.01;
%                     g.speed = 0;
%                     g.dcycle = 1;
%                     g.onset = 0;
%                     g.duration = 0.5;
%                     % add grating to rig
%                     rig.gratings(g);   
%                     % add synch square low
%                     g = [];
%                     g.angle = 0;
%                     g.width = 10;
%                     g.height = 10;
%                     g.size = 10;
%                     g.x = 0;
%                     g.y = -30;
%                     g.contrast = 1;
%                     g.opacity = 1;
%                     g.phase = 0;
%                     g.freq = 0.01;
%                     g.speed = 0;
%                     g.dcycle = 0;
%                     g.onset = 0.5;
%                     g.duration = 0.5;
%                     % add grating to rig
%                     rig.gratings(g);   
                    
                    % generate commands to send stim to BV server
                    for iFeature = 1:length(expDataEval(completeStimSeq(iTrial)).features)
                        featureParamsCell = expDataEval(completeStimSeq(iTrial)).features(iFeature).params;
                        featureParams = cell2struct(expDataEval(completeStimSeq(iTrial)).features(iFeature).vals',expDataEval(completeStimSeq(iTrial)).features(iFeature).params);
                        % determine feature type and build stimulus
                        switch bvData.expData.stims(completeStimSeq(iTrial)).features(iFeature).name{1}
                            case 'grating'
                                g = [];
                                g.angle = str2num(featureParams.angle);
                                g.width = str2num(featureParams.width);
                                g.height = str2num(featureParams.height);
                                g.size = str2num(featureParams.width);
                                g.x = str2num(featureParams.x);
                                g.y = str2num(featureParams.y);
                                g.contrast = str2num(featureParams.contrast);
                                g.opacity = str2num(featureParams.opacity);
                                g.phase = str2num(featureParams.phase);
                                g.freq = str2num(featureParams.freq);
                                g.speed = str2num(featureParams.speed);
                                g.dcycle = str2num(featureParams.dcycle);
                                g.onset = str2num(featureParams.onset);
                                g.duration = str2num(featureParams.duration);
                                % add grating to rig
                                rig.gratings(g);
                            case 'go_nogo'
                                go_nogo_trial.enable = true;
                                go_nogo_trial.suppress_duration = str2num(featureParams.suppress_duration);
                                go_nogo_trial.response_start = str2num(featureParams.response_start);
                                go_nogo_trial.response_duration = str2num(featureParams.response_duration);
                                go_nogo_trial.lick_threshold = str2num(featureParams.lick_threshold);
                                go_nogo_trial.go = str2num(featureParams.go)==1;     
                            case 'vr'
                                vr.enable = true;
                                vr.command = featureParams.vr_command;
                                vr.name = featureParams.vr_name;
                            case 'movie'
                                v = [];
                                v.name  = featureParams.name;
                                % convert name to last folder of filename
                                spstr = strsplit(v.name,'\');
                                v.name = spstr{end};
                                v.angle = str2num(featureParams.angle);
                                v.width = str2num(featureParams.width);
                                v.height = str2num(featureParams.height);
                                v.x = str2num(featureParams.x);
                                v.y = str2num(featureParams.y);
                                v.loop = str2num(featureParams.loop);
                                v.speed = str2num(featureParams.speed);
                                v.onset = str2num(featureParams.onset);
                                v.duration = str2num(featureParams.duration);
                                % add movie to rig
                                rig.video(v);
                            case 'opto'
                                % arm the arduino laser controller
                                if strcmp(featureParams.enable,'1')
                                % suppress,start response window,response window duration,lick threshold
                                % load stimulation config to arduino
                                % rig.go(0, 0, 100, 0); % go trial
                                % Arduino UDP server settings
                                arduino_ip = '158.109.210.169';  % Replace with your Arduino's IP address
                                arduino_port = 8888;  % Replace with your Arduino's port number
                                
                                % Create a UDP port using the modern MATLAB interface.
                                u_opto = udpport("datagram", "IPV4");
                                u_opto.Timeout = 5;
                                
                                try
                                    % Message format: D,Fs,L,DC                                
                                    msg_str = featureParams.opto_command;
                                    message = uint8(msg_str);  % Encoding the message to bytes
                                
                                    % Send message
                                    fprintf('Sending message to Arduino UDP server: %s\n', msg_str);
                                    write(u_opto, message, 'uint8', arduino_ip, arduino_port);
                                
                                    % Attempt to receive a response within the timeout period
                                    try
                                        data = app.waitForUdpBytes(u_opto, u_opto.Timeout, false);
                                        fprintf('Received: %s\n', char(data)');
                                    catch
                                        % Handle the timeout case
                                        fprintf('Timed out waiting for a response\n');
                                    end
                                catch
                                    fprintf('An error occurred\n');
                                end
                                
                                % Clean up
                                fprintf('Closing socket\n');
                                app.cleanupUdpSocket(u_opto);
                                end
                        end

                    end
                    if needsDefaultTriggerGrating
                        app.debugMessage('Added default zero-contrast grating for opto_2p-only trial');
                        app.addDefaultBonvisionTriggerGrating(rig);
                    end
                    % once all features are added
                    % preload resources
                    % preload all video/image files
                    all_resources = [];
                    for iFeat = 1:length(expDataEval(iStim).features)
                      % check if feature is a movie and if so add it as a resource
                      if strcmp(expDataEval(iStim).features(iFeat).name{1},'movie')
                        all_resources{end+1} = expDataEval(iStim).features(iFeat).vals{1};
                        debugMessage(app,['Added resource: ',expDataEval(iStim).features(iFeat).vals{1}]);
                      end
                    end
            
                    all_resources = unique(all_resources);
                    for iRes = 1:length(all_resources)
                      rig.resource(all_resources{iRes});
                    end
                    
                    if ~isempty(all_resources)
                        debugMessage(app,'Preloading trail resources');
                        drawnow
                        tic
                        rig.preload();
                        load_time = toc;
                        debugMessage(app,['Preload command returned ',num2str(load_time),' secs']);
                        debugMessage(app,['Pausing ', app.PauseafterpreloadEditField.Value, ' secs more to ensure preload complete']);
                        drawnow
                        pauseTime = str2double(app.PauseafterpreloadEditField.Value);
                        app.pauseWithEvents(pauseTime);
                    else
                        rig.preload();
                        app.pauseWithEvents(0.5);
                    end

                    % start trial
                    % check if it has a go/nogo feature
                    if go_nogo_trial.enable
                        if go_nogo_trial.go
                            % go trial
                            debugMessage(app,'go/nogo GO trial start requested');
                            rig.pulseValve()
                            rig.success()
                            % start trial (replaces rig.start())
                            rig.go(go_nogo_trial.suppress_duration, ...
                                   go_nogo_trial.response_start, ...
                                   go_nogo_trial.response_duration, ...
                                   go_nogo_trial.lick_threshold)
                        else
                            % nogo trial
                            debugMessage(app,'go/nogo NOGO trial start requested');
                            rig.success()
                            % start trial (replaces rig.start())
                            rig.nogo(go_nogo_trial.suppress_duration, ...
                                   go_nogo_trial.response_start, ...
                                   go_nogo_trial.response_duration, ...
                                   go_nogo_trial.lick_threshold)
                        end
                    elseif vr.enable
                        % vr trial
                        debugMessage(app,'VR trial start requested');
                        eval(featureParams.vr_command);
                        debugMessage(app,['Starting VR: ',vr.name]);
                    else
                        % passive trial
                        debugMessage(app,'Passive trial start requested');
                        rig.start();
                    end
                                                    

                    %debugMessage(app,['before go']);
                    
                    % rig.go(1, 0, 0, 0);
                    %rig.go(100, 100, 100, 0);
                    %rig.go(1000, 500, 1000, 0);
                    %debugMessage(app,['after go']);
                    % start a trial using the go command to initialise optogenetic stimulation
                    % this should generate a trial start trigger
                    %rig.go(0, 0, 0, 0);
                    % wait for end of trial
                    %oscrecv(u
                    datagram = [];
                    while(isempty(datagram))
                        datagram = u.receive();
                        drawnow limitrate;
                    end
                    if trialOpto2pData.enabled && ~app.abortFlag
                        [success, trialOpto2pErr] = app.waitForOpto2pIdle(config);
                        if ~success
                            app.requestRunAbort(trialOpto2pErr);
                            break;
                        end
                    end

                    %pause(0.5);
                    % check if abort has been pressed
                    if app.abortFlag
                        break;
                    end
                    % wait for inter trial period
                    pauseTime = itiSeq(iTrial);
                    startTime = tic;
                    while (toc(startTime)<pauseTime) && app.abortFlag == 0
                        % wait
                        drawnow limitrate;
                    end
                end
                
                % Stop bonvision ouputting timing pulses
                rig.clear();
                rig.experiment('');
               
                
                % 10 second post experiment delay
                app.debugMessage('Post-experiment 10 second pause...');
                pauseTime = 10;
                startTime = tic;
                while (toc(startTime)<pauseTime)
                    % wait
                    drawnow limitrate;
                end
                
                % save experiment data
                expDat.expID = expID;
                expDat.stimOrder = completeStimSeq(1:iTrial);
                expDat.stims = expDataEval;
                expDat.itiSeq = itiSeq(1:iTrial);
                save(fullfile(expSavePath,[expID,'_stim.mat']),'expDat');
                % save in format for python
                saveForPython(app,expDat,expID,expSavePath);
                %
            end

            % Stop all DAQs
            app.debugMessage('Attempting to stop all DAQs');
            % attempt to stop all of the daqs in reverse
            app.debugMessage(['DAQ stop dir: ',config.daqStopDir]);
            app.debugMessage('Listing DAQ stop scripts...');
            daqList = dir(fullfile(config.daqStopDir,'*.m'));
            daqList = {daqList.name}';
            app.debugMessage(['Found ',num2str(length(daqList)),' DAQ stop script(s).']);
            app.debugMessage('Changing to DAQ stop dir...');
            cd(config.daqStopDir);
            app.debugMessage('Changed to DAQ stop dir.');
            for iDaqStop = length(daqList):-1:1
                app.debugMessage(['Checking DAQ stop script ',num2str(iDaqStop),'/',num2str(length(daqList)),': ',daqList{iDaqStop}]);
                if daqEnabled(iDaqStop)
                    app.debugMessage(['Stopping ',daqList{iDaqStop}]);
                    try
                        [success,resp_msg] = eval([daqList{iDaqStop}(1:end-2),'(expID)']);
                    catch daqStopErr
                        success = false;
                        resp_msg = daqStopErr.message;
                    end
                    if ~success
                        app.debugMessage(['Error stopping ',daqList{iDaqStop}]);
                        if ~isempty(resp_msg)
                            app.debugMessage(['Stop error detail for ',daqList{iDaqStop},': ',resp_msg]);
                            err_msg = [err_msg,'; ', ['Error stopping ',daqList{iDaqStop},': ',resp_msg]];
                        else
                            err_msg = [err_msg,'; ', ['Error stopping ',daqList{iDaqStop}]];
                        end
                    else
                        % app.debugMessage('OK');
                    end
                else
                    app.debugMessage(['Skipping disabled DAQ stop script ',daqList{iDaqStop}]);
                end
            end

            app.debugMessage(['Returning to start dir: ',startDir]);
            cd(startDir);
            app.debugMessage('Returned to start dir.');

            [success, abortPhotoStimErr] = app.abortOpto2p(config);
            if ~success
                app.debugMessage(abortPhotoStimErr);
            end

            % request BV computer to copy data to server after local cleanup
            bv_udp_server = bv_address;
            bv_udp_port = 64645;
            remote_path_python = strcat(remotePath,'\',animalID,'\',expID);
            remote_path_python = strrep(remote_path_python,'\','/');          
            msg = "sync" + " " + bvSavePath + " "  + remote_path_python;
            app.debugMessage(['Sending sync command to ',char(bv_udp_server),':',num2str(bv_udp_port),' and waiting up to 30s for a reply']);
            [syncDispatched, syncDetail] = app.send_udp_command_with_prompt(bv_udp_server, bv_udp_port, msg, 30, 'Bonvision sync');
            if syncDispatched == 1
                app.debugMessage('sync Command succeeded.');
            elseif strcmp(syncDetail, 'Timed out waiting for UDP reply.')
                app.debugMessage(['sync Command timed out. host=', char(bv_udp_server), ' port=', num2str(bv_udp_port), ' cmd=', char(msg)]);
            else
                app.debugMessage(['sync Command failed. host=', char(bv_udp_server), ' port=', num2str(bv_udp_port), ' cmd=', char(msg), ' detail=', app.jsonValueToText(syncDetail)]);
            end

            debugMessage(app,['Experiment complete - ',expID]);
            [inp_text, discardExperiment] = app.promptFinalComment();
            if discardExperiment
                app.markExperimentForDeletion(expSavePath);
            end
            if strcmp(app.ExperimentlogTextArea.Value{end},'')
                app.ExperimentlogTextArea.Value{end} = inp_text;
            else
                app.ExperimentlogTextArea.Value{end+1} = inp_text;
            end
            app.ExperimentlogTextArea.Value{end+1} = datestr(datetime);
            % save log file
            formatSpec= '%s\r\n';
            value = app.ExperimentlogTextArea.Value;  % Value entered in textArea
            % log to experiment log file
            f = fopen(fullfile(expSavePath,'exp_log.txt'),'w');
            for i =1:length(value)
                fprintf(f,formatSpec,value{i});
            end
            % append error msg to log
            fprintf(f,formatSpec,err_msg);
            fclose(f);

            % hash all data created on server
            debugMessage(app,['Hashing ',expID]);
            drawnow
            if isempty(config.pythonExe) || isempty(config.hashScript)
                debugMessage(app,'Hashing skipped: python_exe or hash_script is not configured.');
            else
                command = ['"', config.pythonExe, '" "', config.hashScript, '" "', expSavePath, '" "nas" "False"'];
                return_code = dos(command);
                if return_code == 0
                    debugMessage(app,'Hashing complete!');
                    %dos(['explorer.exe ' expSavePath])
                else
                    debugMessage(app,'Hashing Error! Ensure the configured python_exe and hash_script are correct.');
                end
            end

            % put run button back to default state
            app.RunButton.Enable = "on";
            app.RunButton.Text = 'Run';
            app.RunButton.BackgroundColor = 'g';
            app.abortFlag = 0;
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            global bvData;
            config = app.getRepoConfig();
            bvData.settings.bvServer = app.BVServerEditField.Value;
            iniData = config.iniData;
            iniData = app.setIniValue(iniData,'settings','bv_server',app.BVServerEditField.Value);
            app.writeIniFile(config.iniPath, iniData);
        end

        % Value changing function: RepsEditField
        function RepsEditFieldValueChanging(app, event)
            global bvData;
            changingValue = event.Value;
            % check there is a selected stimulus
            if ~isempty(app.StimulusListBox.Tag)
                % validate value (check it is a number)
                if ~isnan(str2double(changingValue))
                    bvData.expData.stims(str2num(app.StimulusListBox.Tag)).reps = str2num(changingValue);
                end
            end
        end

        % Button pushed function: ButtonRemoveFeature
        function ButtonRemoveFeaturePushed(app, event)
            global bvData;
            currentStim = str2num(app.StimulusListBox.Tag);
            currentFeature = str2num(app.FeatureListBox.Tag);
            featsToPreserve = setdiff(1:length(bvData.expData.stims(currentStim).features),currentFeature);
            bvData.expData.stims(currentStim).features = bvData.expData.stims(currentStim).features(featsToPreserve);
            app.FeatureListBox.Tag = '';
            app.FeatureListBox.Value = {};
            app.bvUpdateGUI;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % put run button back to default state
            app.RunButton.Enable = "on";
            app.RunButton.Text = 'Run';
            app.RunButton.BackgroundColor = 'g';
            app.abortFlag = 0;

            app.TestStimBtn.Text = 'Test';
            app.TestStimBtn.BackgroundColor = 'g';
        end

        % Button pushed function: Rewardv1Button
        function Rewardv1ButtonPushed(app, event)
            %subplot(app.UIAxes,)
            %             app.FeedbackPanel.AutoResizeChildren = 'off';
            %             ax = subplot(1,2,1,'Parent',app.FeedbackPanel);
        end

        % Button pushed function: BuildSetButton
        function BuildSetButtonPushed(app, event)
            % takes current stimulus and varies it over a range
            global bvData;
            paramName = [];
            paramVals = [];
            baseStim = inputdlg('What is the base stimulus number?');
            baseStim = str2num(baseStim{1});
            if isempty(baseStim);return;end
            if or(baseStim > length(bvData.expData.stims),baseStim < 1)
                msgbox(['Base stimulus doesn''t exist - there are only ',num2str(length(bvData.expData.stims)),' stimuli']);
                return;
            end
            featToVary = inputdlg('Which features do you want to vary the parameters of?');
            featToVary = eval(featToVary{1});
            if isempty(featToVary);return;end
            if or(max(featToVary)>length(bvData.expData.stims(baseStim).features),min(featToVary)<1)
                msgbox(['At least one feature of stimulus doesn''t exist - there are only ',num2str(length(bvData.expData.stims(baseStim).features)),' features']);
                return;
            end
            resp = questdlg('Replace base stimulus?');

            if strcmp(resp,'Yes')
                overwriteBase = true;
            else
                overwriteBase = false;
            end

            while true
                resp1 = inputdlg('Please enter name of parameter to vary');
                resp2 = inputdlg('Enter desired values (can be in format x,y,z or x:inc:z)');
                if or(isempty(resp1{1}),isempty(resp2{1}))
                    uiwait(msgbox('Param name / vals invalid','Success','modal'));
                end
                try
                    paramVals{end+1} = eval(resp2{1});
                catch
                    uiwait(msgbox('Param values syntax invalid','Success','modal'));
                end
                paramName{end+1} = resp1{1};
                resp = questdlg('Add another parameter?');
                switch resp
                    case 'No'
                        break;
                    case 'Cancel'
                        msgbox('Stimulus generation cancelled');
                        return;
                end
            end

            expData = bvData.expData;
            baseStimParams = bvData.expData.stims(baseStim);

            % build param table
            paramTable = [];
            for iParam = 1:length(paramName)
                if isempty(paramTable)
                    paramTable = paramVals{iParam}';
                else
                    tempTable = [];
                    numValsInParam = length(paramVals{iParam});
                    for iRow = 1:size(paramTable)
                        % reproduce each row with all vals of the new param
                        tempBlock = [repmat(paramTable(iRow,:),[numValsInParam,1]),paramVals{iParam}'];
                        tempTable = [tempTable;tempBlock];
                    end
                    paramTable = tempTable;
                end

            end

            newStimSet = bvData.expData.stims;

            for iStim = 1:size(paramTable,1)
              newStim = baseStimParams;
              for iParam = 1:size(paramTable,2)
                for iFeature = 1:length(featToVary)
                  currentFeature = featToVary(iFeature);
                  paramIndex = find(strcmp(newStim.features(currentFeature).params, paramName{iParam}));
                  if isempty(paramIndex)
                    msgbox(['A parameter wasn''t found (',paramName{iParam},')']);
                    return;
                  end
                  newStim.features(currentFeature).vals(paramIndex) = {num2str(paramTable(iStim,iParam))};
                end
              end
              newStimSet(end+1) = newStim;
            end

            % remove base stim from set if requested
            if overwriteBase
                newStimSet = newStimSet(setdiff(1:length(newStimSet),baseStim));
            end

            bvData.expData.stims = newStimSet;
            app.bvUpdateGUI;
        end

        % Button pushed function: TestStimBtn
        function TestStimBtnButtonPushed(app, event)
            % check if we want to run or abort depending on button label
            % value
            switch app.TestStimBtn.Text
                case 'Running'
                    % don't need to do anything more
                    % return;
                case 'Test'
                    % allow run to initiate
                    app.TestStimBtn.Text = 'Running';
                    app.TestStimBtn.BackgroundColor = 'r';
            end

            global bvData;
            config = app.getRepoConfig();
            remotePath = config.remoteSaveRoot;
            % get a new unique animal ID
            expID = newExpID('STIMTEST');
            animalID = expID(15:end);
            savePath = remotePath;

            app.debugMessage('==========================');

            % make sure bonvision server is in idle state
            % request BV computer to make exp dir
            bv_address = app.BVServerEditField.Value;
            try
                u = OscTcp(bv_address, 4002);
                rig = Rig(u);
                rig.clear();
                rig.experiment('');
            catch
                % bonvision server probably not in use
                app.debugMessage('Problem clearing Bonvision');
                app.TestStimBtn.Text = 'Test';
                app.TestStimBtn.BackgroundColor = 'g';
                return;
            end

            completeStimSeq = str2num(app.StimulusListBox.Value);
            testAborted = false;

            if ~isempty(completeStimSeq)
                % establish udp connection
                %global u;
                metadata = expID;
                u = OscTcp(bv_address, 4002);

                % fopen(u);
                rig = Rig(u);
                rig.dataset(savePath);
                app.photoStimStarted = false;

                debugMessage(app,['Testing stimulus ', num2str(completeStimSeq)]);

                % pull stim variables out of the gui text box
                allVars = app.VariablesEditField.Value;
                % put each var into a struct
                allVars = strsplit(allVars,';');
                varStruc = [];
                for iVar = 1:length(allVars)
                    if ~isempty(allVars{iVar})
                        eval(['varStruc.',allVars{iVar}]);
                    end
                end

                % convert all variable stim params to their values defined in the gui:
                expDataEval = bvData.expData.stims;
                for iStim = 1:length(expDataEval)
                    for iFeature = 1:length(expDataEval(iStim).features)
                        featureParamsCell = expDataEval(iStim).features(iFeature).params;
                        featureParams = cell2struct(expDataEval(iStim).features(iFeature).vals',expDataEval(iStim).features(iFeature).params);
                        % cycle through feature params checking if they have been set
                        % using a variable
                        for iParam = 1:length(featureParamsCell)
                            if isfield(varStruc,featureParams.(featureParamsCell{iParam}))
                                % then it is a variable
                                variableVal = varStruc.(featureParams.(featureParamsCell{iParam}));
                                % convert to string if needed
                                if ~isstring(variableVal)
                                    variableVal = num2str(variableVal);
                                end
                                expDataEval(iStim).features(iFeature).vals{iParam} = variableVal;
                            end
                        end
                    end
                end

                [stimulusConditions, conditionIndexByStimIdx, conditionCatalogErr] = app.buildStimulusConditionCatalog(expDataEval, completeStimSeq);
                if ~isempty(conditionCatalogErr)
                    app.debugMessage(conditionCatalogErr);
                    rig.clear();
                    rig.experiment('');
                    app.TestStimBtn.Text = 'Test';
                    app.TestStimBtn.BackgroundColor = 'g';
                    return;
                end
                trialConditionIndices = conditionIndexByStimIdx(completeStimSeq);
                [success, updateParamsErr] = app.sendExperimentParams(config, expID, stimulusConditions, trialConditionIndices);
                if ~success
                    app.debugMessage(updateParamsErr);
                    rig.clear();
                    rig.experiment('');
                    app.TestStimBtn.Text = 'Test';
                    app.TestStimBtn.BackgroundColor = 'g';
                    return;
                end

                [opto2pPrep, opto2pErr] = app.collectOpto2pPrepData(expDataEval, completeStimSeq);
                if ~isempty(opto2pErr)
                    app.debugMessage(['Opto_2p prep error: ', opto2pErr]);
                    rig.clear();
                    rig.experiment('');
                    app.TestStimBtn.Text = 'Test';
                    app.TestStimBtn.BackgroundColor = 'g';
                    return;
                end
                if opto2pPrep.enabled
                    [success, opto2pErr] = app.runOpto2pPrep(config, expID, opto2pPrep);
                    if ~success
                        app.debugMessage(['Opto_2p prep error: ', opto2pErr]);
                        rig.clear();
                        rig.experiment('');
                        app.TestStimBtn.Text = 'Test';
                        app.TestStimBtn.BackgroundColor = 'g';
                        return;
                    end
                end

                % start loop of going through each trial (only 1 here)
                for iTrial = 1:length(completeStimSeq)
                    drawnow
                    iStim = completeStimSeq(iTrial);
                    [trialOpto2pData, trialOpto2pErr] = app.collectTrialOpto2pData(expDataEval, iStim);
                    if ~isempty(trialOpto2pErr)
                        app.debugMessage(['Opto_2p trigger error: ', trialOpto2pErr]);
                        testAborted = true;
                        break;
                    end
                    if trialOpto2pData.enabled
                        [success, startTrialErr] = app.startOpto2pTrial(config, conditionIndexByStimIdx(iStim));
                        if ~success
                            app.debugMessage(startTrialErr);
                            testAborted = true;
                            break;
                        end
                        [success, trialOpto2pErr] = app.triggerOpto2pForTrial(config, expID, trialOpto2pData);
                        if ~success
                            app.debugMessage(trialOpto2pErr);
                            testAborted = true;
                            break;
                        end
                    end
                    rig.experiment(metadata);
                    needsDefaultTriggerGrating = app.trialNeedsDefaultBonvisionTrigger(expDataEval, iStim);
                    % generate commands to send stim to BV server
                    for iFeature = 1:length(bvData.expData.stims(completeStimSeq(iTrial)).features)
                        featureParamsCell = bvData.expData.stims(completeStimSeq(iTrial)).features(iFeature).params;
                        featureParams = cell2struct(bvData.expData.stims(completeStimSeq(iTrial)).features(iFeature).vals',bvData.expData.stims(completeStimSeq(iTrial)).features(iFeature).params);
                        % cycle through feature params checking if they have been set
                        % using a variable
                        for iParam = 1:length(featureParamsCell)
                            if isfield(varStruc,featureParams.(featureParamsCell{iParam}))
                                % then it is a variable
                                variableVal = varStruc.(featureParams.(featureParamsCell{iParam}));
                                % convert to string if needed
                                if ~isstring(variableVal)
                                    variableVal = num2str(variableVal);
                                end
                                featureParams.(featureParamsCell{iParam}) = variableVal;
                            end
                        end
                        % determine feature type and build stimulus
                        switch bvData.expData.stims(completeStimSeq(iTrial)).features(iFeature).name{1}
                            case 'grating'
                                g = [];
                                g.angle = str2num(featureParams.angle);
                                g.width = str2num(featureParams.width);
                                g.height = str2num(featureParams.height);
                                g.size = str2num(featureParams.width);
                                g.x = str2num(featureParams.x);
                                g.y = str2num(featureParams.y);
                                g.contrast = str2num(featureParams.contrast);
                                g.opacity = str2num(featureParams.opacity);
                                g.phase = str2num(featureParams.phase);
                                g.freq = str2num(featureParams.freq);
                                g.speed = str2num(featureParams.speed);
                                g.dcycle = str2num(featureParams.dcycle);
                                g.onset = str2num(featureParams.onset);
                                g.duration = str2num(featureParams.duration);
                                % add grating to rig
                                rig.gratings(g);
                            case 'movie'
                                v = [];
                                v.name  = featureParams.name;
                                % convert name to last folder of filename
                                spstr = strsplit(v.name,'\');
                                v.name = spstr{end};
                                v.angle = str2num(featureParams.angle);
                                v.width = str2num(featureParams.width);
                                v.height = str2num(featureParams.height);
                                v.x = str2num(featureParams.x);
                                v.y = str2num(featureParams.y);
                                v.loop = str2num(featureParams.loop);
                                v.speed = str2num(featureParams.speed);
                                v.onset = str2num(featureParams.onset);
                                v.duration = str2num(featureParams.duration);
                                % add movie to rig
                                rig.video(v);
                        end
                    end
                    if needsDefaultTriggerGrating
                        app.debugMessage('Added default zero-contrast grating for opto_2p-only trial');
                        app.addDefaultBonvisionTriggerGrating(rig);
                    end
                    % once all features are added, preload trial resources
                    all_resources = [];
                    for iFeat = 1:length(expDataEval(iStim).features)
                        if strcmp(expDataEval(iStim).features(iFeat).name{1},'movie')
                            all_resources{end+1} = expDataEval(iStim).features(iFeat).vals{1};
                            debugMessage(app,['Added resource: ',expDataEval(iStim).features(iFeat).vals{1}]);
                        end
                    end
                    all_resources = unique(all_resources);
                    for iRes = 1:length(all_resources)
                        rig.resource(all_resources{iRes});
                    end

                    if ~isempty(all_resources)
                        debugMessage(app,'Preloading trial resources');
                        drawnow
                        tic
                        rig.preload();
                        load_time = toc;
                        debugMessage(app,['Preload command returned ',num2str(load_time),' secs']);
                        debugMessage(app,['Pausing ', app.PauseafterpreloadEditField.Value, ' secs more to ensure preload complete']);
                        drawnow
                        pauseTime = str2double(app.PauseafterpreloadEditField.Value);
                        app.pauseWithEvents(pauseTime);
                    else
                        rig.preload();
                        app.pauseWithEvents(0.5);
                    end

                    % start trial
                    rig.start();
                    % wait for end of trial
                    datagram = [];
                    while(isempty(datagram))
                        datagram = u.receive();
                        drawnow limitrate;
                    end
                    if trialOpto2pData.enabled
                        [success, trialOpto2pErr] = app.waitForOpto2pIdle(config);
                        if ~success
                            app.debugMessage(trialOpto2pErr);
                            testAborted = true;
                            break;
                        end
                    end
                end

                % Stop bonvision ouputting timing pulses
                rig.clear();
                rig.experiment('');
                if app.photoStimStarted
                    [success, abortPhotoStimErr] = app.abortOpto2p(config);
                    if ~success
                        app.debugMessage(abortPhotoStimErr);
                    end
                end
                %u.delete;
            end

            % put run button back to default state
            app.TestStimBtn.Text = 'Test';
            app.TestStimBtn.BackgroundColor = 'g';
            if testAborted
                debugMessage(app,'Stimulus test aborted');
            else
                debugMessage(app,'Stimulus complete');
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            global bvData;
            currentStim = str2num(app.StimulusListBox.Tag);
            bvData.expData.stims(end+1).features = bvData.expData.stims(currentStim).features;
            bvData.expData.stims(end).reps = bvData.expData.stims(currentStim).reps;
            app.StimulusListBox.Tag = num2str(length(bvData.expData.stims));
            app.FeatureListBox.Tag = '';
            app.bvUpdateGUI;
        end

        % Button pushed function: FeatCopyButton
        function FeatCopyButtonPushed(app, event)
            global bvData;
            currentStim = str2num(app.StimulusListBox.Tag);
            currentFeature = str2num(app.FeatureListBox.Tag);
            bvData.expData.stims(currentStim).features(currentFeature+1)=bvData.expData.stims(currentStim).features(currentFeature);
            app.FeatureListBox.Tag = '';
            app.FeatureListBox.Value = {};
            app.bvUpdateGUI;
        end

        % Value changed function: VariablesEditField
        function VariablesEditFieldValueChanged(app, event)
            value = app.VariablesEditField.Value;
        end

        % Value changing function: VariablesEditField
        function VariablesEditFieldValueChanging(app, event)
            global bvData;
            changingValue = event.Value;
            bvData.expData.vars = changingValue;
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            if ~isempty(app.currentExperimentLogPath) && exist(app.currentExperimentLogPath,'file')
                dos(['notepad.exe "',app.currentExperimentLogPath,'"'])
            else
                debugMessage(app,'No experiment log is available for this session.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulusListBoxLabel = uilabel(app.UIFigure);
            app.StimulusListBoxLabel.Text = 'Stimulus';
            app.StimulusListBox = uilistbox(app.UIFigure);
            app.StimulusListBox.Items = {};
            app.StimulusListBox.ValueChangedFcn = createCallbackFcn(app, @StimulusListBoxValueChanged, true);
            app.StimulusListBox.Value = {};
            app.FeatureListBoxLabel = uilabel(app.UIFigure);
            app.FeatureListBoxLabel.Text = 'Feature';
            app.FeatureListBox = uilistbox(app.UIFigure);
            app.FeatureListBox.Items = {};
            app.FeatureListBox.ValueChangedFcn = createCallbackFcn(app, @FeatureListBoxValueChanged, true);
            app.FeatureListBox.Value = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {'Row 1'; 'Row2'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NewFeatureListBoxLabel = uilabel(app.UIFigure);
            app.NewFeatureListBoxLabel.Text = 'New Feature';
            app.NewFeatureListBox = uilistbox(app.UIFigure);
            app.NewFeatureListBox.Items = {};
            app.NewFeatureListBox.ValueChangedFcn = createCallbackFcn(app, @NewFeatureListBoxValueChanged, true);
            app.NewFeatureListBox.Value = {};
            app.ButtonAddFeature = uibutton(app.UIFigure, 'push');
            app.ButtonAddFeature.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddFeaturePushed, true);
            app.ButtonAddFeature.Text = '+';
            app.ButtonRemoveFeature = uibutton(app.UIFigure, 'push');
            app.ButtonRemoveFeature.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemoveFeaturePushed, true);
            app.ButtonRemoveFeature.Text = '-';
            app.ButtonAddStim = uibutton(app.UIFigure, 'push');
            app.ButtonAddStim.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddStimPushed, true);
            app.ButtonAddStim.Text = '+';
            app.ButtonRemoveStim = uibutton(app.UIFigure, 'push');
            app.ButtonRemoveStim.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemoveStimPushed, true);
            app.ButtonRemoveStim.Text = '-';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.ImportSchemaButton = uibutton(app.UIFigure, 'push');
            app.ImportSchemaButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSchemaButtonPushed, true);
            app.ImportSchemaButton.Text = 'Import schema';
            app.NewFeatureListBoxLabel_2 = uilabel(app.UIFigure);
            app.NewFeatureListBoxLabel_2.Text = 'Feature parameters';
            app.Openvalve1Button = uibutton(app.UIFigure, 'push');
            app.Openvalve1Button.Text = 'Open valve 1';
            app.Closevalve1Button = uibutton(app.UIFigure, 'push');
            app.Closevalve1Button.Text = 'Close valve 1';
            app.Openvalve2Button = uibutton(app.UIFigure, 'push');
            app.Openvalve2Button.Text = 'Open valve 2';
            app.Closevalve2Button = uibutton(app.UIFigure, 'push');
            app.Closevalve2Button.Text = 'Close valve 2';
            app.Rewardv1Button = uibutton(app.UIFigure, 'push');
            app.Rewardv1Button.ButtonPushedFcn = createCallbackFcn(app, @Rewardv1ButtonPushed, true);
            app.Rewardv1Button.Text = 'Reward v1';
            app.Rewardv2Button = uibutton(app.UIFigure, 'push');
            app.Rewardv2Button.Text = 'Reward v2';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.BVServerEditFieldLabel = uilabel(app.SettingsPanel);
            app.BVServerEditFieldLabel.Text = 'BV Server';
            app.BVServerEditField = uieditfield(app.SettingsPanel, 'text');
            app.BVServerEditField.Value = '127.0.0.1';
            app.SavesettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SequenceRepeatsEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceRepeatsEditFieldLabel.Text = {'Sequence'; 'Repeats'};
            app.SequenceRepeatsEditField = uieditfield(app.UIFigure, 'text');
            app.SequenceRepeatsEditField.Value = '10';
            app.RepsEditFieldLabel = uilabel(app.UIFigure);
            app.RepsEditFieldLabel.Text = 'Reps';
            app.RepsEditField = uieditfield(app.UIFigure, 'text');
            app.RepsEditField.ValueChangingFcn = createCallbackFcn(app, @RepsEditFieldValueChanging, true);
            app.RepsEditField.Value = '1';
            app.RandomiseCheckBox = uicheckbox(app.UIFigure);
            app.RandomiseCheckBox.Text = 'Randomise';
            app.RandomiseCheckBox.Value = true;
            app.FeedbackListBox = uilistbox(app.UIFigure);
            app.FeedbackListBox.Items = {};
            app.FeedbackListBox.Value = {};
            app.UITable_DAQs = uitable(app.UIFigure);
            app.UITable_DAQs.ColumnName = {''};
            app.UITable_DAQs.RowName = {'Row 1'; 'Row2'};
            app.UITable_DAQs.ColumnEditable = true;
            app.AnimalIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnimalIDEditField.Value = 'TEST';
            app.BuildSetButton = uibutton(app.UIFigure, 'push');
            app.BuildSetButton.ButtonPushedFcn = createCallbackFcn(app, @BuildSetButtonPushed, true);
            app.BuildSetButton.Text = 'Build set';
            app.DelaysecsLabel = uilabel(app.UIFigure);
            app.DelaysecsLabel.Text = {'Delay (secs)'; ''};
            app.PreBlankText = uieditfield(app.UIFigure, 'text');
            app.PreBlankText.Value = '0';
            app.TestStimBtn = uibutton(app.UIFigure, 'push');
            app.TestStimBtn.ButtonPushedFcn = createCallbackFcn(app, @TestStimBtnButtonPushed, true);
            app.TestStimBtn.Text = 'Test';
            app.VariablesEditFieldLabel = uilabel(app.UIFigure);
            app.VariablesEditFieldLabel.Text = 'Variables';
            app.VariablesEditField = uieditfield(app.UIFigure, 'text');
            app.VariablesEditField.ValueChangedFcn = createCallbackFcn(app, @VariablesEditFieldValueChanged, true);
            app.VariablesEditField.ValueChangingFcn = createCallbackFcn(app, @VariablesEditFieldValueChanging, true);
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.FeatCopyButton = uibutton(app.UIFigure, 'push');
            app.FeatCopyButton.ButtonPushedFcn = createCallbackFcn(app, @FeatCopyButtonPushed, true);
            app.FeatCopyButton.Text = 'Copy';
            app.ITIEditFieldLabel = uilabel(app.UIFigure);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITIEditField = uieditfield(app.UIFigure, 'text');
            app.ITIEditField.Value = '2';
            app.ExperimentlogTextAreaLabel = uilabel(app.UIFigure);
            app.ExperimentlogTextAreaLabel.Text = 'Experiment log';
            app.ExperimentlogTextArea = uitextarea(app.UIFigure);
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            app.PauseafterpreloadEditFieldLabel = uilabel(app.UIFigure);
            app.PauseafterpreloadEditFieldLabel.Text = 'Pause after preload';
            app.PauseafterpreloadEditField = uieditfield(app.UIFigure, 'text');
            app.PauseafterpreloadEditField.Value = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bvGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end